classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HelpPanel                      matlab.ui.container.Panel
        Hyperlink                      matlab.ui.control.Hyperlink
        Image2                         matlab.ui.control.Image
        PartialVolumeCorrectionMethodsPanel  matlab.ui.container.Panel
        PARSLRButton                   matlab.ui.control.Button
        RoussetButton                  matlab.ui.control.Button
        LucyRichardsonButton           matlab.ui.control.Button
        MullerGartnerButton            matlab.ui.control.Button
        VisualizationPanel             matlab.ui.container.Panel
        VisualizeButton                matlab.ui.control.Button
        TitleEditField                 matlab.ui.control.EditField
        TitleEditFieldLabel            matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        pathLabel                      matlab.ui.control.Label
        SelectdirectoryButton          matlab.ui.control.Button
        UIAxes1                        matlab.ui.control.UIAxes
        QuantificationPanel            matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        NormalizationTab               matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        ScaledCorrectedPETinNativeSpaceCheckBox  matlab.ui.control.CheckBox
        ScaledUncorrectedPETinMNIspaceCheckBox  matlab.ui.control.CheckBox
        PVCPETinMNIspaceCheckBox       matlab.ui.control.CheckBox
        ScaledUncorrectedPETinNativeSpaceCheckBox  matlab.ui.control.CheckBox
        UncorrectedPETinMNIspaceCheckBox  matlab.ui.control.CheckBox
        PVCimplementedDropDown         matlab.ui.control.DropDown
        PVCimplementedLabel            matlab.ui.control.Label
        NormalizationSpaceDropDown     matlab.ui.control.DropDown
        NormalizationSpaceDropDownLabel  matlab.ui.control.Label
        InitialsofMRimageEditField     matlab.ui.control.EditField
        InitialsofMRimageEditFieldLabel  matlab.ui.control.Label
        InitialsofPETimageEditField    matlab.ui.control.EditField
        InitialsofPETimageEditFieldLabel  matlab.ui.control.Label
        pathAtlas                      matlab.ui.control.Label
        MaskButton                     matlab.ui.control.Button
        RUNButton                      matlab.ui.control.Button
        pathLabel_2                    matlab.ui.control.Label
        SelectdirectoryButton_2        matlab.ui.control.Button
        RegionalQuantificationTab      matlab.ui.container.Tab
        AtlasButton                    matlab.ui.control.Button
        SelectdirectoryButton_3        matlab.ui.control.Button
        PVCimplementedDropDown_2       matlab.ui.control.DropDown
        PVCimplementedDropDown_2Label  matlab.ui.control.Label
        InitialsofMRimageEditField_2   matlab.ui.control.EditField
        InitialsofMRimageEditField_2Label  matlab.ui.control.Label
        InitialsofPETimageEditField_2  matlab.ui.control.EditField
        InitialsofPETimageEditField_2Label  matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        RegionalquantificationforuncorrectedPETCheckBox  matlab.ui.control.CheckBox
        pathAtlas_2                    matlab.ui.control.Label
        RUNButton_2                    matlab.ui.control.Button
        pathLabel_3                    matlab.ui.control.Label
        HomePanel                      matlab.ui.container.Panel
        Image3                         matlab.ui.control.Image
        PARTIALVOLUMECORRECTIONTOOLBOXLabel  matlab.ui.control.Label
        QuantificationButton           matlab.ui.control.Button
        HelpButton                     matlab.ui.control.Button
        VisualizationButton            matlab.ui.control.Button
        PVCMethodsButton               matlab.ui.control.Button
        HomeButton                     matlab.ui.control.Button
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = public)
        InitialPanel 
        VisualizationImage
        titleImage

        %Quantification Normalization
        PathFolder
        maskfullpath
        pName
        mName
        pvcImplemented
        nSpace
        MNIpet
        MNIpvcpet
        SWpetmni
        WNSpvcpet
        NSpet

        %Regional Quantification
        PathFolder2
        maskfullpath2
        pName2
        mName2
        pvcImplemented2
        regionalQ
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ocultar todos los paneles
            app.HomePanel.Visible = 'on';
            app.PartialVolumeCorrectionMethodsPanel.Visible = 'off';
            app.VisualizationPanel.Visible = 'off';
            app.QuantificationPanel.Visible = 'off';
            app.HelpPanel.Visible = 'off';
            
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.HomePanel.Visible = 'on';
            app.PartialVolumeCorrectionMethodsPanel.Visible = 'off';
            app.VisualizationPanel.Visible = 'off';
            app.QuantificationPanel.Visible = 'off';
            app.HelpPanel.Visible = 'off';
        end

        % Button pushed function: PVCMethodsButton
        function PVCMethodsButtonPushed(app, event)
            app.PartialVolumeCorrectionMethodsPanel.Visible = 'on';
            app.HomePanel.Visible = 'off';
            app.VisualizationPanel.Visible = 'off';
            app.QuantificationPanel.Visible = 'off';
            app.HelpPanel.Visible = 'off';
        end

        % Button down function: HomePanel
        function HomePanelButtonDown(app, event)
            %app.HomePanel.Visible = 'off';
        end

        % Button pushed function: VisualizationButton
        function VisualizationButtonPushed(app, event)
            app.VisualizationPanel.Visible = 'on';
            app.HomePanel.Visible = 'off';
            app.PartialVolumeCorrectionMethodsPanel.Visible = 'off';
            app.QuantificationPanel.Visible = 'off';
            app.HelpPanel.Visible = 'off';
        end

        % Button pushed function: MullerGartnerButton
        function MullerGartnerButtonPushed2(app, event)
            muller
            delete(app)
        end

        % Button pushed function: LucyRichardsonButton
        function LucyRichardsonButtonPushed(app, event)
            lucy
            delete(app)
        end

        % Button pushed function: RoussetButton
        function RoussetButtonPushed(app, event)
            % Guarda mainApp en appdata para que sea accesible desde la segunda aplicación
            rousset
            delete(app)
        end

        % Button pushed function: PARSLRButton
        function PARSLRButtonPushed(app, event)
            PARSLR
            delete(app)
        end

        % Button pushed function: SelectdirectoryButton
        function SelectdirectoryButtonPushed2(app, event)
            app.VisualizationImage=spm_select(1);
            if ~isempty(app.VisualizationImage)
                app.pathLabel.Text = app.VisualizationImage;
            end
            figure(app.UIFigure);
        end

        % Button down function: UIAxes1
        function UIAxes1ButtonDown(app, event)
            
            if ~isempty(app.VisualizationImage)
                vol1 = spm_vol(app.VisualizationImage);
                img1 = spm_read_vols(vol1);
    
                imagesc(app.UIAxes1, img1(:,:,160));
            end
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;
            app.titleImage = value;
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            titleText = app.titleImage;
            pathI = app.VisualizationImage;

            if isempty(titleText)
               clc
               msgbox('Enter the title', ...
                'Partial Volume Correction', 'warn');
               return;
            elseif isempty(pathI)
               clc
               msgbox('Choose the image you want to visualize', ...
                'Partial Volume Correction', 'warn');
               return;
            end

            % Verification nifti file
            [~, ~, ext] = fileparts(pathI);
            if ~strcmpi(ext, '.nii')
                clc
                msgbox('Please choose a .nii image file.', 'Partial Volume Correction', 'warn');
                return;
            end
            
            vol = spm_vol(pathI);
            img = spm_read_vols(vol);
            
            titleObj = title(app.UIAxes1, titleText);
            middleSlice = round(size(img, 3) / 2);
            imagesc(app.UIAxes1, img(:,:,middleSlice))
            c = colorbar(app.UIAxes1);

        end

        % Button pushed function: QuantificationButton
        function QuantificationButtonPushed(app, event)
            app.QuantificationPanel.Visible = 'on';
            app.PartialVolumeCorrectionMethodsPanel.Visible = 'off';
            app.HomePanel.Visible = 'off';
            app.VisualizationPanel.Visible = 'off';
            app.HelpPanel.Visible = 'off';
        end

        % Button pushed function: SelectdirectoryButton_2
        function SelectdirectoryButton_2Pushed(app, event)
            figure(app.UIFigure);
            app.PathFolder=uigetdir();
            figure(app.UIFigure);
            if app.PathFolder ~= 0
                app.pathLabel_2.Text = app.PathFolder;
            end
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            app.maskfullpath=spm_select(1);
            if ~isempty(app.maskfullpath)
                app.pathAtlas.Text = app.maskfullpath;
            end
            figure(app.UIFigure);
        end

        % Value changed function: InitialsofPETimageEditField
        function InitialsofPETimageEditFieldValueChanged(app, event)
            value = app.InitialsofPETimageEditField.Value;
            app.pName = value;
        end

        % Value changed function: InitialsofMRimageEditField
        function InitialsofMRimageEditFieldValueChanged(app, event)
            value = app.InitialsofMRimageEditField.Value;
            app.mName = value;
        end

        % Value changed function: PVCimplementedDropDown
        function PVCimplementedDropDownValueChanged(app, event)
            value = app.PVCimplementedDropDown.Value;
            app.pvcImplemented = value;
        end

        % Value changed function: NormalizationSpaceDropDown
        function NormalizationSpaceDropDownValueChanged(app, event)
            value = app.NormalizationSpaceDropDown.Value;
            app.nSpace = value;
        end

        % Value changed function: UncorrectedPETinMNIspaceCheckBox
        function UncorrectedPETinMNIspaceCheckBoxValueChanged(app, event)
            value = app.UncorrectedPETinMNIspaceCheckBox.Value;
            app.MNIpet = value;
        end

        % Value changed function: PVCPETinMNIspaceCheckBox
        function PVCPETinMNIspaceCheckBoxValueChanged(app, event)
            value = app.PVCPETinMNIspaceCheckBox.Value;
            app.MNIpvcpet = value;
        end

        % Value changed function: ScaledUncorrectedPETinMNIspaceCheckBox
        function ScaledUncorrectedPETinMNIspaceCheckBoxValueChanged(app, event)
            value = app.ScaledUncorrectedPETinMNIspaceCheckBox.Value;
            app.SWpetmni = value;
        end

        % Value changed function: ScaledUncorrectedPETinNativeSpaceCheckBox
        function ScaledUncorrectedPETinNativeSpaceCheckBoxValueChanged(app, event)
            value = app.ScaledUncorrectedPETinNativeSpaceCheckBox.Value;
            app.NSpet = value;
        end

        % Value changed function: ScaledCorrectedPETinNativeSpaceCheckBox
        function ScaledCorrectedPETinNativeSpaceCheckBoxValueChanged(app, event)
            value = app.ScaledCorrectedPETinNativeSpaceCheckBox.Value;
            app.WNSpvcpet = value;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            Method=app.nSpace;
            PName=app.pName;
            MRIName=app.mName;
            M1='MNI Space';
            M2='Native Space Conventional';
            M3='Native Space New';
            PVCMethod=app.pvcImplemented;
            PVC1='Muller Gartner';
            PVC2='Lucy Richardson';
            pathFolder=app.PathFolder;
            Maskfullpath=app.maskfullpath;
            mnipet=app.MNIpet;
            mnipvcpet=app.MNIpvcpet;
            swpetmni=app.SWpetmni;
            wnspvcpet=app.WNSpvcpet;
            nspet=app.NSpet;

            if isempty(Method) || isempty(pathFolder) || isempty(Maskfullpath) || isempty(PVCMethod)...
                || isempty(PName) || isempty(MRIName)
                clc
               msgbox('There is not enough input for initiating Partial Volume Correction', ...
                'Partial Volume Correction', 'warn');
               return
            elseif Method == "Select one"
                clc
                msgbox('Select one Normalization Space', ...
                'Partial Volume Correction', 'warn');
                return 
            elseif PVCMethod == "Select one"
                clc
                msgbox('Select the PVC implemented', ...
                'Partial Volume Correction', 'warn');
                return 
            elseif strcmp(Method,M1)==1  && (any(wnspvcpet)||any(nspet))
                clc
                msgbox('Select Native Space from the dropdown for been able to accomplish your specifications',...
                    'Partial Volume Correction', 'warn')
                return
            elseif (strcmp(Method,M2)==1  || strcmp(Method,M3)==1 ) &&...
                (any(mnipet) || any(mnipvcpet))
                clc
                msgbox('Select MNI space from the dropdown for been able to accomplish your specifications',...
                    'Partial Volume Correction', 'warn')
                return
            end
            d = dir(pathFolder);
            isub = [d(:).isdir]; %# returns logical vector
            dirlist = {d(isub).name}';
            dirlist(1:2) = [];
            kk=1:length(dirlist);
            CurrentDir=pwd;
            Variables=whos;
            save Variables

            %% SPM Defaults

            spm_get_defaults;
            spm_jobman('initcfg')
            clc
            
            for kk=1:length(dirlist)
                
            wb=waitbar(0.2,['Loading files for  ' dirlist{kk}],'Name', 'Quantitative Normalization');
            
            pause(10)
            if  ishandle(wb)
                mkdir([pathFolder '/' dirlist{kk}],'ScaledImage')
                format=Maskfullpath(end-3:end);
                Name=strfind(Maskfullpath,'\')
                MName=Maskfullpath(Name(end)+1:end)
            if strcmp(format,'.nii')==1
                copyfile(Maskfullpath,[pathFolder '/' dirlist{kk} '/ScaledImage']);
                MaskFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/' MName];
            elseif strcmp(format,'.hdr')==1
                copyfile(Maskfullpath,[pathFolder '/' dirlist{kk} '/ScaledImage']);
                copyfile([Maskfullpath(1:end-4) '.img'],[pathFolder '/' dirlist{kk} '/ScaledImage']);
                MName=[MName(1:end-4) '.img'];
                MaskFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/' MName(1:end-4) '.img'];
            elseif strcmp(format,'.img')==1
                copyfile(Maskfullpath,[pathFolder '/' dirlist{kk} '/ScaledImage']);
                copyfile([Maskfullpath(1:end-4) '.hdr'],[pathFolder '/' dirlist{kk} '/ScaledImage']);
                MaskFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/' MName];
            end
                PETfile=dir([pathFolder '/' dirlist{kk} '/Sourcefiles/'  PName,'*.nii']);
                PETFullPath=[pathFolder '/' dirlist{kk}  '/Sourcefiles/' PETfile.name];
                PVCfile=dir([pathFolder '/' dirlist{kk} '/' PName '*PVC_','*.nii']);
                PVCFullPath=[pathFolder '/' dirlist{kk} '/' PVCfile.name];    
                PETvol={[PETFullPath,',',int2str(1)]};
                PVCvol={[PVCFullPath,',',int2str(1)]};
                GMPVC=dir([pathFolder '/' dirlist{kk} '/GM_' PName ,'*.nii']);
            if ~isempty(GMPVC)
                 copyfile([pathFolder '/' dirlist{kk} '/' GMPVC.name],[pathFolder '/' dirlist{kk} '/ScaledImage']);
            end
                wb=waitbar(0.4,wb,['Processing Files for  ' dirlist{kk}]);
                
            else
               clc
               box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
            %    rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
               break
            end
            if isempty(Method)
              box=msgbox({'Choose Space for Quantification: MNI or Native'},'Quantitative Normalization','warn');
              delete(wb)
            break    
            elseif strcmp(Method,M1)==1 
            pause(10)
            if ishandle(wb)
            %% Coregister
            MRIfile=dir([pathFolder '/' dirlist{kk} '/Sourcefiles/' MRIName,'*.nii']);
            MRIFullPath=[pathFolder '/' dirlist{kk}  '/Sourcefiles/' MRIfile.name];
            MRIvol={[MRIFullPath,',',int2str(1)]};
            Fieldfile=dir([pathFolder '/' dirlist{kk} '/y_','*.nii']);
            if isempty(Fieldfile) || isempty(PETfile) || isempty(PVCfile)
                box=msgbox({'There is not enough inputs for quantitative normalization in '; dirlist{kk}},'Quantitative Normalization','warn');
                delete(wb)
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
            break
            else
            FFullPath=[pathFolder '/' dirlist{kk}  '/'  Fieldfile.name];
            matlabbatch{1}.spm.spatial.coreg.estimate.ref = MRIvol;
            matlabbatch{1}.spm.spatial.coreg.estimate.source = PETvol;
            if isempty(GMPVC)
            matlabbatch{1}.spm.spatial.coreg.estimate.other = PVCvol;
            else
                GMPVCFullpath=[pathFolder '/' dirlist{kk} '/ScaledImage/' GMPVC.name];
                GMPVCvol={[GMPVCFullpath,',',int2str(1)]};
                others=[PVCvol GMPVCvol]';
                matlabbatch{1}.spm.spatial.coreg.estimate.other = others;
            end
            spm_jobman('run', matlabbatch);
            clc
            clear matlabbatch
            end
              wb=waitbar(0.6,wb,['Processing Files for  ' dirlist{kk}]);
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
            break
            end
            
            
            %% Normalize.write
            pause(10)
            if ishandle(wb) 
            if isempty(GMPVC)
            
            PETvol_content = PETvol{1};
            split_content = strsplit(PETvol_content, ',');
            Pet = split_content{1};
            
            PVCvol_content = PVCvol{1};
            split_content = strsplit(PVCvol_content, ',');
            Pvc = split_content{1};
            
            pets{1} = Pet;
            pets{2} = Pvc;
            
            
            matlabbatch{1}.spm.spatial.normalise.write.subj.def = {FFullPath};
            matlabbatch{1}.spm.spatial.normalise.write.subj.resample = pets';
            matlabbatch{1}.spm.spatial.normalise.write.woptions.bb = [-78 -112 -70; 78 76 88];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.vox = [2 2 2];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.interp = 4;
            
            else
                PETvol_content = PETvol{1};
                split_content = strsplit(PETvol_content, ',');
                Pet = split_content{1};
                
                PVCvol_content = PVCvol{1};
                split_content = strsplit(PVCvol_content, ',');
                Pvc = split_content{1};
            
                GMPVCvol_content = GMPVCvol{1};
                split_content = strsplit(GMPVCvol_content, ',');
                Gmpvc = split_content{1};
            
            pets{1} = Pet;
            pets{2} = Pvc;
            pets{3} = Gmpvc;
            matlabbatch{1}.spm.spatial.normalise.write.subj.def = {FFullPath};
            matlabbatch{1}.spm.spatial.normalise.write.subj.resample = pets';
            matlabbatch{1}.spm.spatial.normalise.write.woptions.bb = [NaN NaN NaN; NaN NaN NaN];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.vox = [2 2 2];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.interp = 0;
            end
            spm_jobman('run', matlabbatch);
            clc
            clear matlabbatch
            
            wPETFullPath=[pathFolder '/' dirlist{kk}  '/Sourcefiles/w' PETfile.name];
            movefile(wPETFullPath,[pathFolder '/' dirlist{kk} '/ScaledImage']);
            wPVCFullPath=[pathFolder '/' dirlist{kk}  '/w' PVCfile.name];
            movefile(wPVCFullPath,[pathFolder '/' dirlist{kk} '/ScaledImage']);
            wb=waitbar(0.8,wb,['Processing Files for  ' dirlist{kk}]);   
                    
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
            break
            end
            
            %% Reslice
            pause(10)
            if ishandle(wb)  
            WPVCFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/w'  PVCfile.name];
            WPETFullPath=[pathFolder '/' dirlist{kk}  '/ScaledImage/w' PETfile.name];
            flags = struct('mask',1,'interp',0,'mean',0,'which',1,'wrap',[0 0 0]);
            if isempty(GMPVC)
            Image{1} = MaskFullPath;
            Image{2} = WPVCFullPath;
            Image{3} = WPETFullPath;
            P = {Image{1:3}}';
            else
            GMWPVCFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/w'  GMPVC.name];
            Image{1} = MaskFullPath;
            Image{2} = WPVCFullPath;
            Image{3} = WPETFullPath;
            Image{4} = GMWPVCFullPath;
            P = {Image{1:4}}';
            end
            spm_reslice(P,flags)
            clc
            wb=waitbar(0.9,wb,['Scaling PET Image for  ' dirlist{kk}]);
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
            break
            end
            %% Quantitative Normalization
            pause(5)
            if ishandle(wb)
            rWPVCFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/rw'  PVCfile.name];
            rWPETFullPath=[pathFolder '/' dirlist{kk}  '/ScaledImage/rw' PETfile.name];
            PET_nii=load_untouch_nii(rWPETFullPath);
            UncorPET=PET_nii.img;
            PVC=spm_read_vols(spm_vol(rWPVCFullPath));
            Mask=spm_read_vols(spm_vol(MaskFullPath));
            Mask(Mask~=0)=1;
            if isempty(GMPVC) && ~isempty(mnipet)
            Scaled=PVC./mean(PVC(Mask==1));
            UncorrScaled=UncorPET./mean(UncorPET(Mask==1));
            elseif isempty(GMPVC) && isempty(mnipet)
            Scaled=PVC./mean(PVC(Mask==1));
            elseif ~isempty(GMPVC) && isempty(mnipet)
            rWGMPVCFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/rw'  GMPVC.name];
            GM_PVC=spm_read_vols(spm_vol(rWGMPVCFullPath));
            Scaled=PVC./mean(PVC(Mask==1));
            GMScaled=Scaled;
            GMScaled(GM_PVC==0)=0;
            elseif ~isempty(GMPVC) && ~isempty(mnipet)
            rWGMPVCFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/rw'  GMPVC.name];
            GM_PVC=spm_read_vols(spm_vol(rWGMPVCFullPath));
            Scaled=PVC./mean(PVC(Mask==1));
            GMScaled=Scaled;
            GMScaled(GM_PVC==0)=0;
            UncorrScaled=UncorPET./mean(UncorPET(Mask==1));
            end
            wb=waitbar(0.95,wb,['Saving Scaled Image for  ' dirlist{kk}]);
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
            break
            end
            
            pause(5)
            if ishandle(wb)
            if any(mnipet)
            movefile(WPETFullPath,[pathFolder '/' dirlist{kk}])
            end
            if any(mnipvcpet)
            movefile(WPVCFullPath,[pathFolder '/' dirlist{kk}])
            end
            nii = make_nii(Scaled);
            nii.hdr.hist = PET_nii.hdr.hist;
            nii.hdr.dime.pixdim = PET_nii.hdr.dime.pixdim;
            newname = [ 'MScaled_rw' PVCfile.name];
            save_nii(nii,newname);
            if any(swpetmni) && any(mnipet)
            Uncornii = make_nii(UncorrScaled);
            Uncornii.hdr.hist = PET_nii.hdr.hist;
            Uncornii.hdr.dime.pixdim = PET_nii.hdr.dime.pixdim;
            Uncorrnewname = [ 'MScaled_rw' PETfile.name];
            save_nii(Uncornii,Uncorrnewname);
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
                movefile(Uncorrnewname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1 
            movefile(Uncorrnewname,dirlist{kk}) 
            end
            end
            
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
            movefile(newname,[pathFolder '/' dirlist{kk}])   
            elseif strcmp(CurrentDir,pathFolder)==1 
             movefile(newname,dirlist{kk}) 
            end
            if ~isempty(GMPVC)
            nii = make_nii(GMScaled);
            nii.hdr.hist = PET_nii.hdr.hist;
            nii.hdr.dime.pixdim = PET_nii.hdr.dime.pixdim;
            gnewname = [ 'MScaled_rw_' GMPVC.name];
            save_nii(nii,gnewname);
            
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
               movefile(gnewname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1 
             movefile(gnewname,dirlist{kk})   
            end
            end
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break 
            end
            clear nii
            if strcmp(PVCMethod,PVC1)==1  
               spm_smooth([pathFolder '/' dirlist{kk} '/' newname],[pathFolder '/' dirlist{kk} '/s' newname],[8 8 8],0);
            elseif strcmp(PVCMethod,PVC2)==1
                 spm_smooth([pathFolder '/' dirlist{kk} '/' gnewname],[pathFolder '/' dirlist{kk} '/s' gnewname],[8 8 8],0);
            end
            rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
            elseif strcmp(Method,M1)==0
            pause(10)
            if ishandle(wb)
            Maskvol = {[MaskFullPath,',',int2str(1)]};
            InvFieldfile=dir([pathFolder '/' dirlist{kk} '/iy_','*.nii']);
            if isempty(InvFieldfile)|| isempty(PETfile) || isempty(PVCfile)
                box=msgbox({'There is not enough inputs for quantitative normalization in '; dirlist{kk}},...
                    'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                delete(wb)
                break 
            else
            InvFFullPath=[pathFolder '/' dirlist{kk}  '/'  InvFieldfile.name];
            matlabbatch{1}.spm.spatial.normalise.write.subj.def = {InvFFullPath};
            matlabbatch{1}.spm.spatial.normalise.write.subj.resample = cellstr(Maskvol);
            matlabbatch{1}.spm.spatial.normalise.write.woptions.bb = [NaN NaN NaN; NaN NaN NaN];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.vox = [2 2 2];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.interp = 0;
            end
            spm_jobman('run', matlabbatch);
            clc
            clear matlabbatch
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break 
            end
            wb=waitbar(0.6,wb,['Processing Files for  ' dirlist{kk}]);
            pause(10)
            if ishandle(wb)    
            wMaskFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/w' MName];
            wMaskvol={[wMaskFullPath,',',int2str(1)]};
            matlabbatch{1}.spm.spatial.coreg.estimate.ref = PETvol;
            matlabbatch{1}.spm.spatial.coreg.estimate.source = wMaskvol;
            wb=waitbar(0.65,wb,['Processing Files for  ' dirlist{kk}]);
            else
                clc
                box=msgbox({'Quantitative Normalization Aborted for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break 
            end
            pause(10)
            if ishandle(wb)
            if strcmp(Method,M3)==1
            MRIfile=dir([pathFolder '/' dirlist{kk} '/Sourcefiles/' MRIName,'*.nii']);    
            c1path=[pathFolder '/' dirlist{kk} '/c1' MRIfile.name];
            copyfile(c1path,[pathFolder '/' dirlist{kk} '/ScaledImage'])
            nc1path=[pathFolder '/' dirlist{kk} '/ScaledImage/c1' MRIfile.name];
            nc1vol={[nc1path,',',int2str(1)]};
            matlabbatch{1}.spm.spatial.coreg.estimate.other = nc1vol;
            spm_jobman('run', matlabbatch);
            clc
            clear matlabbatch    
            end
            else
                clc
                box=msgbox({'Quantitative Normalization Aborted for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break 
            end
            wb=waitbar(0.7,wb,['Processing Files for  ' dirlist{kk}]);
            pause(10)
            if ishandle(wb)
            if strcmp(Method,M1)==0 
            %Reslice
            flags = struct('mask',1,'interp',0,'mean',0,'which',1,'wrap',[0 0 0]);
            Image{1} = PETFullPath;
            Image{2} = wMaskFullPath;
            end
            wb=waitbar(0.8,wb,['Processing Files for  ' dirlist{kk}]);
            else
                clc
                box=msgbox({'Quantitative Normalization Aborted for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break 
            end
            pause(5)
            if ishandle(wb)
            if strcmp(Method,M3)==1 
            Image{3} =nc1path;
            P = {Image{1:3}}';
            elseif strcmp(Method,M2)==1 
            P = {Image{1:2}}';
            end
            spm_reslice(P,flags)
            clc
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break 
            end
            
            %% Scaling
            wb=waitbar(0.8,wb,['Scaling PET for  ' dirlist{kk}]);
            pause(5)
            if ishandle(wb)
            PET_nii=load_untouch_nii(PETFullPath);
            PVC=spm_read_vols(spm_vol(PVCFullPath));
            rwMaskFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/rw' MName];
            Mask=spm_read_vols(spm_vol(rwMaskFullPath));
            Mask(Mask~=0)=1;
            if isempty(GMPVC) && strcmp(Method,M2)==1
            Scaled=PVC./mean(PVC(Mask==1));
            elseif isempty(GMPVC) && strcmp(Method,M3)==1
                rc1FullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/rc1' MRIfile.name];
                rc1=spm_read_vols(spm_vol( rc1FullPath));
                Tr=str2double(PVCfile.name(end-6:end-4));
                rc1(rc1<=Tr)=0;
                rc1(rc1~=0)=1;
                NMask=rc1.*Mask;
                Scaled=PVC./mean(PVC(NMask==1));
            if any(nspet)
                PET=PET_nii.img;
                uncorrPET=PET./mean(PVC(NMask==1));
            end
            elseif ~isempty(GMPVC) && strcmp(Method,M2)==1
            GMPVCFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/'  GMPVC.name];
            GM_PVC=spm_read_vols(spm_vol(GMPVCFullPath));
            Scaled=PVC./mean(PVC(Mask==1));
            GMScaled=Scaled;
            GMScaled(GM_PVC==0)=0;
            elseif ~isempty(GMPVC) && strcmp(Method,M3)==1
            GMPVCFullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/'  GMPVC.name];
            GM_PVC=spm_read_vols(spm_vol(GMPVCFullPath));
            rc1FullPath = [pathFolder '/' dirlist{kk} '/ScaledImage/rc1' MRIfile.name];
            rc1=spm_read_vols(spm_vol( rc1FullPath));
            Tr=str2double(PVCfile.name(end-6:end-4));
            rc1(rc1<=Tr)=0;
            rc1(rc1~=0)=1;
            NMask=rc1.*Mask;
            Scaled=PVC./mean(PVC(Mask==1));
            GMScaled=Scaled;
            GMScaled(GM_PVC==0)=0;
            if any(nspet)
                PET=PET_nii.img;
                uncorrPET=PET./mean(PVC(NMask==1));
            end
            end
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break 
            end
            wb=waitbar(0.85,wb,['Saving Scaled PET for  ' dirlist{kk}]);
            pause(5)
            if ishandle(wb)
            nii = make_nii(Scaled);
            nii.hdr.hist = PET_nii.hdr.hist;
            nii.hdr.dime.pixdim = PET_nii.hdr.dime.pixdim;
            newname = [ 'NScaled_' PVCfile.name];
            save_nii(nii,newname);
            if any(nspet)
            nspetnii = make_nii(uncorrPET);
            nspetnii.hdr.hist = PET_nii.hdr.hist;
            nspetnii.hdr.dime.pixdim = PET_nii.hdr.dime.pixdim;
            nspetnewname = [ 'NScaled_' PETfile.name];
            save_nii(nspetnii,nspetnewname);
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
            movefile(nspetnewname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1 
             movefile(nspetnewname,dirlist{kk})   
            end
            end
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
               movefile(newname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1 
             movefile(newname,dirlist{kk})   
            end
            if ~isempty(GMPVC)
            nii = make_nii(GMScaled);
            nii.hdr.hist = PET_nii.hdr.hist;
            nii.hdr.dime.pixdim = PET_nii.hdr.dime.pixdim;
            gnewname = [ 'NScaled_' GMPVC.name];
            save_nii(nii,gnewname);
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
               movefile(gnewname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1 
             movefile(gnewname,dirlist{kk})   
            end
            end
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break
            end
            wb=waitbar(0.95,wb,['Saving Scaled PET for  ' dirlist{kk}]);
            pause(5)
            if ishandle(wb)
            if any(swpetmni) || any(wnspvcpet)   
            Fieldfile=dir([pathFolder '/' dirlist{kk} '/y_','*.nii']);
            if isempty(Fieldfile) 
                box=msgbox({'There is not enough inputs for quantitative normalization in '; dirlist{kk}},'Quantitative Normalization','warn');
                delete(wb)
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break
            end
            end
            else
            clc
            rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
            box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
            break 
            end
            wb=waitbar(0.96,wb,['Saving Scaled PET for  ' dirlist{kk}]);
            pause(5)
            if ishandle(wb)
            
            if ~isempty(swpetmni) && ~isempty(wnspvcpet) && ~isempty(nspet) && strcmp(Method,M3)==1
            copyfile([ pathFolder '/' dirlist{kk} '/' nspetnewname], [ pathFolder '/' dirlist{kk} '/ScaledImage']) ; 
            copyfile([ pathFolder '/' dirlist{kk} '/' newname], [ pathFolder '/' dirlist{kk} '/ScaledImage']) ; 
            path1=[ pathFolder '/' dirlist{kk} '/ScaledImage/' nspetnewname];
            vol1={[path1,',',int2str(1)]};
            path2=[ pathFolder '/' dirlist{kk} '/ScaledImage/' newname];
            vol2={[path2,',',int2str(1)]};
            Others=[vol1,vol2];
            elseif (~isempty(swpetmni)|| ~isempty(nspet)) && isempty(wnspvcpet)
            copyfile([ pathFolder '/' dirlist{kk} '/' nspetnewname], [ pathFolder '/' dirlist{kk} '/ScaledImage']);     
            path1=[ pathFolder '/' dirlist{kk} '/ScaledImage/' nspetnewname];
            vol1={[path1,',',int2str(1)]};
            Others=[vol1];
            elseif isempty(swpetmni) && ~isempty(wnspvcpet)
            copyfile([ pathFolder '/' dirlist{kk} '/' newname], [ pathFolder '/' dirlist{kk} '/ScaledImage']);      
            path2=[ pathFolder '/' dirlist{kk} '/ScaledImage/' newname]
            vol2={[path2,',',int2str(1)]};
            Others=[vol2];
            end
            
            MRIfile=dir([pathFolder '/' dirlist{kk} '/Sourcefiles/' MRIName,'*.nii']);  
            MRIFullPath=[pathFolder '/' dirlist{kk}  '/Sourcefiles/' MRIfile.name];
            MRIvol={[MRIFullPath,',',int2str(1)]};
            matlabbatch{1}.spm.spatial.coreg.estimate.ref = MRIvol;
            matlabbatch{1}.spm.spatial.coreg.estimate.source = PETvol;
            matlabbatch{1}.spm.spatial.coreg.estimate.other = Others;
            spm_jobman('run', matlabbatch);
            clear matlabbatch
            wb=waitbar(0.97,wb,['Saving Scaled PET for  ' dirlist{kk}]);
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break 
            end
            
            pause(5)
            
            if ishandle(wb)
            if any(swpetmni) || any(wnspvcpet)   
            FFullPath=[pathFolder '/' dirlist{kk}  '/'  Fieldfile.name];    
            matlabbatch{1}.spm.spatial.normalise.write.subj.def = {FFullPath};
            matlabbatch{1}.spm.spatial.normalise.write.woptions.bb = [-78 -112 -70; 78 76 88];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.vox = [2 2 2];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.interp = 4;
            if any(swpetmni) && any(wnspvcpet) && any(nspet)
                vols{1} = vol1;
                vols{2} = vol2;
            matlabbatch{1}.spm.spatial.normalise.write.subj.resample = vols';
            elseif any(swpetmni) && isempty(wnspvcpet) && any(nspet)
            matlabbatch{1}.spm.spatial.normalise.write.subj.resample = vol1;
            elseif isempty(swpetmni) && any(wnspvcpet)
            matlabbatch{1}.spm.spatial.normalise.write.subj.resample = vol2;
            end
            spm_jobman('run', matlabbatch);
            clear matlabbatch
            clc
            wb=waitbar(98,wb,['Quantitative Scaling Completed for ' dirlist{kk}]) ;
            end
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break 
            end    
            pause(5)
            if ishandle(wb)
            if any(swpetmni) && any(wnspvcpet) && any(nspet)
            wpath1=[ pathFolder '/' dirlist{kk} '/ScaledImage/w' nspetnewname];
            wpath2=[ pathFolder '/' dirlist{kk} '/ScaledImage/w' newname];
            movefile(wpath1,[ pathFolder '/' dirlist{kk}])
            movefile(wpath2,[ pathFolder '/' dirlist{kk}])
            rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
            elseif ~isempty(swpetmni) && isempty(wnspvcpet) && ~isempty(nspet)
            wpath1=[ pathFolder '/' dirlist{kk} '/ScaledImage/w' nspetnewname];
            movefile(wpath1,[ pathFolder '/' dirlist{kk}]);
            elseif isempty(swpetmni) && ~isempty(wnspvcpet)
            wpath2=[ pathFolder '/' dirlist{kk} '/ScaledImage/w' newname];
            movefile(wpath2,[ pathFolder '/' dirlist{kk}])
            end
            wb=waitbar(0.99,wb,['Quantitative Scaling Completed for ' dirlist{kk}]) ;
            else
                clc
                box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
                rmdir([pathFolder '/' dirlist{kk} '/ScaledImage'],'s')
                break 
            end
            end
            wb=waitbar(1,wb,['Quantitative Scaling Completed for ' dirlist{kk}]) ;
            if  kk~=length(dirlist) && ishandle(wb)
            delete(wb)
            load Variables.mat
            clc
            spm_get_defaults;
            spm_jobman('initcfg')
            clc
            elseif kk==length(dirlist) && ishandle(wb)
                savefig(wb,'wb')
            else
               clc
               box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
               break
            end
            end
            %% Output
            O1={['Quantitative Normalization is done for ' pathFolder]};
            O2={['Scaling Mask Directory is ' Maskfullpath]};
            O3={['Images have been corrected by ' PVCMethod]};
            O4={['Scaled Image is in ' Method]};
            O5={['Initials of PET is ' PName]};
            O6={['Initials of MRI is ' MRIName]};
            fid=fopen([pathFolder '/Quantitative_parameters.txt'],'w');
            fprintf(fid,'%s\n',O1{:});
            fprintf(fid,'%s\n',O2{:});
            fprintf(fid,'%s\n',O3{:});
            fprintf(fid,'%s\n',O4{:});
            fprintf(fid,'%s\n',O5{:});
            fprintf(fid,'%s\n',O6{:});


            app.pathLabel_2.Text = '';
            app.InitialsofMRimageEditField.Value = '';
            app.InitialsofPETimageEditField.Value = '';
            app.pathAtlas.Text = '';
            app.PVCimplementedDropDown.Value = "Select one";
            app.NormalizationSpaceDropDown.Value = "Select one";
            app.UncorrectedPETinMNIspaceCheckBox.Value = 0;
            app.PVCPETinMNIspaceCheckBox.Value = 0;
            app.UncorrectedPETinMNIspaceCheckBox = 0;
            app.ScaledUncorrectedPETinNativeSpaceCheckBox.Value = 0;
            app.ScaledCorrectedPETinNativeSpaceCheckBox.Value = 0;

            
        end

        % Button pushed function: SelectdirectoryButton_3
        function SelectdirectoryButton_3Pushed(app, event)
            figure(app.UIFigure);
            app.PathFolder2=uigetdir();
            figure(app.UIFigure);
            if app.PathFolder2 ~= 0
                app.pathLabel_3.Text = app.PathFolder2;
            end

        end

        % Button pushed function: AtlasButton
        function AtlasButtonPushed(app, event)
            app.maskfullpath2=spm_select(1);
            if ~isempty(app.maskfullpath2)
                app.pathAtlas_2.Text = app.maskfullpath2;
            end
            figure(app.UIFigure);
        end

        % Value changed function: InitialsofPETimageEditField_2
        function InitialsofPETimageEditField_2ValueChanged(app, event)
            value = app.InitialsofPETimageEditField_2.Value;
            app.pName2 = value;
        end

        % Value changed function: InitialsofMRimageEditField_2
        function InitialsofMRimageEditField_2ValueChanged(app, event)
            value = app.InitialsofMRimageEditField_2.Value;
            app.mName2 = value;
        end

        % Value changed function: PVCimplementedDropDown_2
        function PVCimplementedDropDown_2ValueChanged(app, event)
            value = app.PVCimplementedDropDown_2.Value;
            app.pvcImplemented2 = value;
        end

        % Value changed function: 
        % RegionalquantificationforuncorrectedPETCheckBox
        function RegionalquantificationforuncorrectedPETCheckBoxValueChanged(app, event)
            value = app.RegionalquantificationforuncorrectedPETCheckBox.Value;
            app.regionalQ = value;
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            Method=app.pvcImplemented2;
            M1='Muller Gartner';
            if strcmp(Method,'Muller Gartner')==1
                str='*_MPVC_';
            elseif strcmp(Method,'Lucy Richardson')==1
                str='*_LRPVC_';
            elseif strcmp(Method,'Rousset')==1
                str='*_RPVC_';
            elseif strcmp(Method,'PARSLR')==1
                str='*_PARSLRPVC_';
            end
            pathFolder=app.PathFolder2;
            Atlasfullpath=app.maskfullpath2;
            PName=app.pName2;
            MRIName=app.mName2;
            check=app.regionalQ;

            if isempty(Method) || isempty(pathFolder) || isempty(Atlasfullpath)...
                || isempty(PName) || isempty(MRIName)
                clc
               msgbox('There is not enough input for initiating Partial Volume Correction', ...
                'Partial Volume Correction', 'warn');
               return
            elseif Method == "Select one"
                clc
                msgbox('Select the PVC implemented', ...
                'Partial Volume Correction', 'warn');
                return 
            else
            
            d = dir(pathFolder);
            isub = [d(:).isdir]; %# returns logical vector
            dirlist = {d(isub).name}';
            dirlist(1:2) = [];
            kk=1:length(dirlist);
            Variables=whos;
            save Variables
            CurrentDir=pwd;
            if ~isempty(Atlasfullpath)
            format=Atlasfullpath(end-3:end);
            Name=strfind(Atlasfullpath,'\');
            MName=Atlasfullpath(Name(end)+1:end);
            end
            % save Variables
            if isempty(check) 
            total1=cell(256,2.*length(dirlist));
            else
            total1=cell(256,2.*length(dirlist));
            total2=total1;
            end
            end
            for kk=1:length(dirlist)
                
                wb=waitbar(0.2,['Loading files for  ' dirlist{kk}],'Name', 'Regional Quantification');
               
            pause(5)
            if  ishandle(wb)
                if strcmp(Method,'Muller Gartner')==1 || strcmp(Method,'Lucy Richardson')==1         
                if strcmp(format,'.nii')==1
                mkdir([pathFolder '/' dirlist{kk}],'Atlas')     
                copyfile(Atlasfullpath,[pathFolder '/' dirlist{kk} '/Atlas']);
                AtlasFullPath = [pathFolder '/' dirlist{kk} '/Atlas/' MName];
                elseif strcmp(format,'.hdr')==1
                mkdir([pathFolder '/' dirlist{kk}],'Atlas')     
                copyfile(Atlasfullpath,[pathFolder '/' dirlist{kk} '/Atlas' ]);
                copyfile([Atlasfullpath(1:end-4) '.img'],[pathFolder '/' dirlist{kk} '/Atlas']);
                AtlasFullPath = [pathFolder '/' dirlist{kk} '/Atlas/' MName(1:end-4) '.img'];
                elseif strcmp(format,'.img')==1
                mkdir([pathFolder '/' dirlist{kk}],'Atlas')     
                copyfile(Atlasfullpath,[pathFolder '/' dirlist{kk} '/Atlas']);
                copyfile([Atlasfullpath(1:end-4) '.hdr'],[pathFolder '/' dirlist{kk} '/Atlas']);
                AtlasFullPath = [pathFolder '/' dirlist{kk} '/Atlas/' MName];
                end
                wb=waitbar(0.25,wb,['Loading files for  ' dirlist{kk}],'Name', 'Regional Quantification');
                else
                 wb=waitbar(0.2,wb,['Loading files for  ' dirlist{kk}],'Name', 'Regional Quantification');   
                end
            else
            clc
            box=msgbox({'Regional Quantification Cancelled for  '; dirlist{kk}},'Regional Quantification','warn');
            end
            
            pause(5)   
            if  ishandle(wb)
            if strcmp(Method,'Muller Gartner')==1 || strcmp(Method,'Lucy Richardson')==1 
            if isempty(check)
            PETfile=dir([pathFolder '/' dirlist{kk}  '/NScaled_' PName str,'*.nii']);
            Scaled_PET=[pathFolder '/' dirlist{kk}  '/' PETfile.name];
            else
            PETfile=dir([pathFolder '/' dirlist{kk}  '/NScaled_' PName str,'*.nii']);
            Scaled_PET=[pathFolder '/' dirlist{kk}  '/' PETfile.name];
            uncorPETfile=dir([pathFolder '/' dirlist{kk}  '/NScaled_' PName ,'*.nii']);
            uncorScaled_PET=[pathFolder '/' dirlist{kk}  '/' uncorPETfile(1).name];
            end
            Maskfile=dir([pathFolder '/' dirlist{kk}  '/rMask','*.nii']);
            Maskpath=[pathFolder '/' dirlist{kk}  '/' Maskfile.name];
            Atlasvol={[AtlasFullPath,',',int2str(1)]};
            %emi_configspm12_bl
            spm_get_defaults;
            spm_jobman('initcfg')
            clc
            PETvol={[Scaled_PET,',',int2str(1)]};
            Fieldfile=dir([pathFolder '/' dirlist{kk} '/iy_','*.nii']);
            FFullPath=[pathFolder '/' dirlist{kk}  '/'  Fieldfile.name];
            matlabbatch{1}.spm.spatial.normalise.write.subj.def = {FFullPath};
            matlabbatch{1}.spm.spatial.normalise.write.subj.resample = cellstr(Atlasvol);
            matlabbatch{1}.spm.spatial.normalise.write.woptions.bb = [NaN NaN NaN; NaN NaN NaN];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.vox = [2 2 2];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.interp = 0;
            spm_jobman('run', matlabbatch);
            clc
            clear matlabbatch
            wAtlasFullPath = [pathFolder '/' dirlist{kk} '/Atlas/w' MName(1:end-4) '.nii'];
            wAtlasvol={[wAtlasFullPath,',',int2str(1)]};
            %Coregister
            matlabbatch{1}.spm.spatial.coreg.estimate.ref = PETvol;
            matlabbatch{1}.spm.spatial.coreg.estimate.source = wAtlasvol;
            spm_jobman('run', matlabbatch);
            clc
            clear matlabbatch
            flags = struct('mask',1,'interp',0,'mean',0,'which',1,'wrap',[0 0 0]);
            Image{1} = Scaled_PET;
            Image{2} = wAtlasFullPath;
            P = {Image{1:2}}';
            spm_reslice(P,flags)
            clc
            elseif strcmp(Method,'Rousset')==1 || strcmp(Method,'PARSLR')==1
            Atlasfile=dir([pathFolder '/' dirlist{kk}  '/rBrain_Atlas_','*.nii']);
            Atlaspath=[pathFolder '/' dirlist{kk}  '/' Atlasfile.name];   
            if isempty(check)
            PETfile=dir([pathFolder '/' dirlist{kk}  '/NScaled_' PName str,'*.nii']);
            Scaled_PET=[pathFolder '/' dirlist{kk}  '/' PETfile.name];              
            else
            PETfile=dir([pathFolder '/' dirlist{kk}  '/NScaled_' PName str,'*.nii']);
            Scaled_PET=[pathFolder '/' dirlist{kk}  '/' PETfile.name];
            uncorPETfile=dir([pathFolder '/' dirlist{kk}  '/NScaled_' PName ,'*.nii']);
            uncorScaled_PET=[pathFolder '/' dirlist{kk}  '/' uncorPETfile(1).name];
            end       
            end
            wb=waitbar(0.5,wb,['Loading files for  ' dirlist{kk}],'Name', 'Regional Quantification');    
            else
            clc
            if strcmp(Method,'Muller Gartner')==1 || strcmp(Method,'Lucy Richardson')==1
            rmdir([pathFolder '/' dirlist{kk} '/Atlas'],'s')
            end
            box=msgbox({'Regional Quantification Cancelled for  '; dirlist{kk}},'Regional Quantification','warn');
            break
            end
            
            pause(10)
            if ishandle(wb)    
            if strcmp(Method,'Muller Gartner')==0 && strcmp(Method,'Lucy Richardson')==0 
                Atlas=spm_read_vols(spm_vol(Atlaspath));
                Atlas(isnan(Atlas))=0;
                elseif strcmp(Method,'Muller Gartner')==1
                  Mask_Brain=spm_read_vols(spm_vol(Maskpath));
                  Mask_Brain(isnan(Mask_Brain))=0;
                  Mask_Brain(Mask_Brain==1)=0;
                  Mask_Brain(Mask_Brain==2)=1;
                  DAtlas=spm_read_vols(spm_vol([pathFolder '/' dirlist{kk} '/Atlas/rw' MName(1:end-4) '.nii']));
                  DAtlas(isnan(DAtlas))=0;
                  Atlas=DAtlas.*Mask_Brain;
                  Headr=load_untouch_nii(Maskpath);
                  nii=make_nii(Atlas);
                  nii.hdr.hist = Headr.hdr.hist;
                  nii.hdr.dime.pixdim = Headr.hdr.dime.pixdim;
                  newname = [ 'Atlas_' PETfile.name(9:end)];
                  save_nii(nii,newname);
                  rmdir([pathFolder '/' dirlist{kk} '/Atlas'],'s')
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
                 movefile(newname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1 
                movefile(newname,dirlist{kk})   
            end
            elseif strcmp(Method,'Lucy Richardson')==1
                  Mask_Brain=spm_read_vols(spm_vol(Maskpath));
                  Headr=load_untouch_nii(Maskpath);
                  Mask_Brain(isnan(Mask_Brain))=0;
                  Mask_Brain(Mask_Brain~=3)=0;
                  Mask_Brain(Mask_Brain==3)=1;
                  DAtlas=spm_read_vols(spm_vol([pathFolder '/' dirlist{kk} '/Atlas/rw' MName(1:end-4) '.img']));
                  DAtlas(isnan(DAtlas))=0;
                  Atlas=DAtlas.*Mask_Brain;
                  nii=make_nii(Atlas);
                  nii.hdr.hist = Headr.hdr.hist;
                  nii.hdr.dime.pixdim = Headr.hdr.dime.pixdim;
                  newname = [ 'Atlas_' PETfile.name(9:end)];
                  save_nii(nii,newname);
                  rmdir([pathFolder '/' dirlist{kk} '/Atlas'],'s')
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
               movefile(newname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1 
             movefile(newname,dirlist{kk})   
            end
            end
            wb=waitbar(0.7,wb,['Loading files for  ' dirlist{kk}],'Name', 'Regional Quantification');     
            else
            clc
            if strcmp(Method,'Muller Gartner')==1 || strcmp(Method,'Lucy Richardson')==1
            rmdir([pathFolder '/' dirlist{kk} '/Atlas'],'s')
            end
            box=msgbox({'Regional Quantification Cancelled for  '; dirlist{kk}},'Regional Quantification','warn');
            break
            end
            
            pause(5)
            if ishandle(wb)
            if isempty(check)    
                PET=double(spm_read_vols(spm_vol(Scaled_PET)));
                PET(isnan(PET))=0;
                case_index=unique(Atlas(Atlas>0));
                Excel1=cell(length(case_index)+1,2);
                Excel1(2:end,1)=num2cell(case_index);
                Excel1(1,1)={'Index'};
                Excel1(1,2)={dirlist{kk}};
                
                for nn=1:length(case_index)
                   
                    Mean(nn,1)=mean(PET(Atlas==case_index(nn)));
                end
            Excel1(2:length(case_index)+1,2)= num2cell(Mean);
            total1(1:length(case_index)+1,2.*kk-1)=Excel1(1:end,1);
            total1(1:length(case_index)+1,2.*kk)=Excel1(1:end,2);
            else
             case_index=unique(Atlas(Atlas>0));
                PET=double(spm_read_vols(spm_vol(Scaled_PET)));
                PET(isnan(PET))=0;
                UncorPET=double(spm_read_vols(spm_vol(uncorScaled_PET)));
                UncorPET(isnan(UncorPET))=0;
                Excel1=cell(length(case_index)+1,2);
                Excel1(2:end,1)=num2cell(case_index);
                Excel1(1,1)={'Index'};
                Excel1(1,2)={dirlist{kk}};
                Excel2=Excel1;
                for nn=1:length(case_index)
                    Mean(nn,1)=mean(PET(Atlas==case_index(nn)));
                    UncorMean(nn,1)=mean(UncorPET(Atlas==case_index(nn)));
                end
  
            Excel1(2:length(case_index)+1,2)= num2cell(Mean);
            Excel2(2:length(case_index)+1,2)= num2cell(UncorMean);
            total1(1:length(case_index)+1,2.*kk-1)=Excel1(1:end,1);
            total1(1:length(case_index)+1,2.*kk)=Excel1(1:end,2);
            total2(1:length(case_index)+1,2.*kk-1)=Excel2(1:end,1);
            total2(1:length(case_index)+1,2.*kk)=Excel2(1:end,2);
            end
            wb=waitbar(0.85,wb,['Performing regional quantification  ' dirlist{kk}],'Name', 'Regional Quantification');
            else   
            clc
            box=msgbox({'Regional Quantification Cancelled for  '; dirlist{kk}},'Regional Quantification','warn');
            break
            end
            
            if isempty(check) 
            clear Excel1
            clear Mean
            else
            clear Excel1
            clear Excel2
            clear Mean
            clear UncorMean
            end
            if  kk~=length(dirlist) && ishandle(wb)
            delete(wb)
            load Variables.mat;
            
            clc
            spm_get_defaults;
            spm_jobman('initcfg')
            clc
            elseif kk==length(dirlist) && ishandle(wb)
                savefig(wb,'wb')
               else
               clc
               box=msgbox({'Quantitative Normalization Cancelled for  '; dirlist{kk}},'Quantitative Normalization','warn');
               break
            end
            end
            pause(5)
               if isempty(check) 
               xlswrite('Corrected_Regional_values.csv',total1)
               EPath=[cd '/Corrected_Regional_values.csv'];
               else
               xlswrite('Corrected_Regional_values.csv',total1)
               EPath1=[cd '/Corrected_Regional_values.csv'];
               xlswrite('Uncorrected_Regional_values.csv',total2)
               EPath2=[cd '/Uncorrected_Regional_values.csv'];
               end
               wb=waitbar(0.95,wb,['Performing regional quantification  ' dirlist{kk}],'Name', 'Regional Quantification');    
            pause(5)
            if ishandle(wb)
               if strcmp(cd,8)==0 
               if isempty(check)   
               movefile(EPath,pathFolder);
               else
               movefile(EPath1,pathFolder);
               movefile(EPath2,pathFolder);
               end
               end      
            wb=waitbar(1,wb,['Importing regional quantification to  ' dirlist{kk}],'Name', 'Regional Quantification');
            else
               clc
               box=msgbox({'Regional Quantification Cancelled for  '; dirlist{kk}},'Regional Quantification','warn');
               return
            end
            O1={['Regional Quantification is done for ' pathFolder]};
            O2={['Partial volume correction method is ' Method]};
            fid=fopen([pathFolder '/RegionalQuantification_parameters.txt'],'w');
            fprintf(fid,'%s\n',O1{:});
            fprintf(fid,'%s\n',O2{:});

            app.pathLabel_3.Text = '';
            app.InitialsofMRimageEditField_2.Value = '';
            app.InitialsofPETimageEditField_2.Value = '';
            app.pathAtlas_2.Text = '';
            app.PVCimplementedDropDown_2.Value = "Select one";
            app.RegionalquantificationforuncorrectedPETCheckBox.Value = 0;

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            qNormalizationINFO
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            qRegionalINFO
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.HelpPanel.Visible = 'on';
            app.QuantificationPanel.Visible = 'off';
            app.PartialVolumeCorrectionMethodsPanel.Visible = 'off';
            app.HomePanel.Visible = 'off';
            app.VisualizationPanel.Visible = 'off';
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            % Define el enlace que deseas abrir
            enlace = 'https://www.google.com';
            
            % Abre el enlace en un navegador web externo
            web(enlace, '-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.PVCMethodsButton = uibutton(app.UIFigure, 'push');
            app.PVCMethodsButton.ButtonPushedFcn = createCallbackFcn(app, @PVCMethodsButtonPushed, true);
            app.PVCMethodsButton.Text = 'PVC Methods';
            app.VisualizationButton = uibutton(app.UIFigure, 'push');
            app.VisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizationButtonPushed, true);
            app.VisualizationButton.Text = 'Visualization';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.QuantificationButton = uibutton(app.UIFigure, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.QuantificationButton.Text = 'Quantification';
            app.HomePanel = uipanel(app.UIFigure);
            app.HomePanel.Title = 'Home';
            app.HomePanel.ButtonDownFcn = createCallbackFcn(app, @HomePanelButtonDown, true);
            app.PARTIALVOLUMECORRECTIONTOOLBOXLabel = uilabel(app.HomePanel);
            app.PARTIALVOLUMECORRECTIONTOOLBOXLabel.Text = 'PARTIAL VOLUME CORRECTION TOOLBOX';
            app.Image3 = uiimage(app.HomePanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Images', 'Home', 'Home.png');
            app.QuantificationPanel = uipanel(app.UIFigure);
            app.QuantificationPanel.Title = 'Quantification';
            app.QuantificationPanel.Visible = 'off';
            app.TabGroup = uitabgroup(app.QuantificationPanel);
            app.NormalizationTab = uitab(app.TabGroup);
            app.NormalizationTab.Title = 'Normalization';
            app.SelectdirectoryButton_2 = uibutton(app.NormalizationTab, 'push');
            app.SelectdirectoryButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectdirectoryButton_2Pushed, true);
            app.SelectdirectoryButton_2.Text = 'Select directory';
            app.pathLabel_2 = uilabel(app.NormalizationTab);
            app.pathLabel_2.Enable = 'off';
            app.pathLabel_2.Text = '*path';
            app.RUNButton = uibutton(app.NormalizationTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.MaskButton = uibutton(app.NormalizationTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.pathAtlas = uilabel(app.NormalizationTab);
            app.pathAtlas.Enable = 'off';
            app.pathAtlas.Text = '*path';
            app.InitialsofPETimageEditFieldLabel = uilabel(app.NormalizationTab);
            app.InitialsofPETimageEditFieldLabel.Text = 'Initials of PET image:';
            app.InitialsofPETimageEditField = uieditfield(app.NormalizationTab, 'text');
            app.InitialsofPETimageEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsofPETimageEditFieldValueChanged, true);
            app.InitialsofMRimageEditFieldLabel = uilabel(app.NormalizationTab);
            app.InitialsofMRimageEditFieldLabel.Text = 'Initials of MR image:';
            app.InitialsofMRimageEditField = uieditfield(app.NormalizationTab, 'text');
            app.InitialsofMRimageEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsofMRimageEditFieldValueChanged, true);
            app.NormalizationSpaceDropDownLabel = uilabel(app.NormalizationTab);
            app.NormalizationSpaceDropDownLabel.Text = 'Normalization Space:';
            app.NormalizationSpaceDropDown = uidropdown(app.NormalizationTab);
            app.NormalizationSpaceDropDown.Items = {'Select One', 'MNI Space', 'Native Space Conventional', 'Native Space New'};
            app.NormalizationSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizationSpaceDropDownValueChanged, true);
            app.NormalizationSpaceDropDown.Value = 'Select One';
            app.PVCimplementedLabel = uilabel(app.NormalizationTab);
            app.PVCimplementedLabel.Text = 'PVC implemented:';
            app.PVCimplementedDropDown = uidropdown(app.NormalizationTab);
            app.PVCimplementedDropDown.Items = {'Select One', 'Muller Gartner', 'Lucy Richardson', 'Rousset', 'Partially Segmented Lucy Richardson'};
            app.PVCimplementedDropDown.ValueChangedFcn = createCallbackFcn(app, @PVCimplementedDropDownValueChanged, true);
            app.PVCimplementedDropDown.Value = 'Select One';
            app.UncorrectedPETinMNIspaceCheckBox = uicheckbox(app.NormalizationTab);
            app.UncorrectedPETinMNIspaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @UncorrectedPETinMNIspaceCheckBoxValueChanged, true);
            app.UncorrectedPETinMNIspaceCheckBox.Text = 'Uncorrected PET in MNI space';
            app.ScaledUncorrectedPETinNativeSpaceCheckBox = uicheckbox(app.NormalizationTab);
            app.ScaledUncorrectedPETinNativeSpaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaledUncorrectedPETinNativeSpaceCheckBoxValueChanged, true);
            app.ScaledUncorrectedPETinNativeSpaceCheckBox.Text = 'Scaled Uncorrected PET in Native Space';
            app.PVCPETinMNIspaceCheckBox = uicheckbox(app.NormalizationTab);
            app.PVCPETinMNIspaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @PVCPETinMNIspaceCheckBoxValueChanged, true);
            app.PVCPETinMNIspaceCheckBox.Text = 'PVC PET in MNI space';
            app.ScaledUncorrectedPETinMNIspaceCheckBox = uicheckbox(app.NormalizationTab);
            app.ScaledUncorrectedPETinMNIspaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaledUncorrectedPETinMNIspaceCheckBoxValueChanged, true);
            app.ScaledUncorrectedPETinMNIspaceCheckBox.Text = 'Scaled Uncorrected PET in MNI space';
            app.ScaledCorrectedPETinNativeSpaceCheckBox = uicheckbox(app.NormalizationTab);
            app.ScaledCorrectedPETinNativeSpaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaledCorrectedPETinNativeSpaceCheckBoxValueChanged, true);
            app.ScaledCorrectedPETinNativeSpaceCheckBox.Text = 'Scaled Corrected PET in Native Space';
            app.Image = uiimage(app.NormalizationTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'MullerGartner', 'info.png');
            app.RegionalQuantificationTab = uitab(app.TabGroup);
            app.RegionalQuantificationTab.Title = 'Regional Quantification';
            app.pathLabel_3 = uilabel(app.RegionalQuantificationTab);
            app.pathLabel_3.Enable = 'off';
            app.pathLabel_3.Text = '*path';
            app.RUNButton_2 = uibutton(app.RegionalQuantificationTab, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.pathAtlas_2 = uilabel(app.RegionalQuantificationTab);
            app.pathAtlas_2.Enable = 'off';
            app.pathAtlas_2.Text = '*path';
            app.RegionalquantificationforuncorrectedPETCheckBox = uicheckbox(app.RegionalQuantificationTab);
            app.RegionalquantificationforuncorrectedPETCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegionalquantificationforuncorrectedPETCheckBoxValueChanged, true);
            app.RegionalquantificationforuncorrectedPETCheckBox.Text = 'Regional quantification for uncorrected PET';
            app.Image_2 = uiimage(app.RegionalQuantificationTab);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Images', 'MullerGartner', 'info.png');
            app.InitialsofPETimageEditField_2Label = uilabel(app.RegionalQuantificationTab);
            app.InitialsofPETimageEditField_2Label.Text = 'Initials of PET image:';
            app.InitialsofPETimageEditField_2 = uieditfield(app.RegionalQuantificationTab, 'text');
            app.InitialsofPETimageEditField_2.ValueChangedFcn = createCallbackFcn(app, @InitialsofPETimageEditField_2ValueChanged, true);
            app.InitialsofMRimageEditField_2Label = uilabel(app.RegionalQuantificationTab);
            app.InitialsofMRimageEditField_2Label.Text = 'Initials of MR image:';
            app.InitialsofMRimageEditField_2 = uieditfield(app.RegionalQuantificationTab, 'text');
            app.InitialsofMRimageEditField_2.ValueChangedFcn = createCallbackFcn(app, @InitialsofMRimageEditField_2ValueChanged, true);
            app.PVCimplementedDropDown_2Label = uilabel(app.RegionalQuantificationTab);
            app.PVCimplementedDropDown_2Label.Text = 'PVC implemented:';
            app.PVCimplementedDropDown_2 = uidropdown(app.RegionalQuantificationTab);
            app.PVCimplementedDropDown_2.Items = {'Select One', 'Muller Gartner', 'Lucy Richardson', 'Rousset', 'Partially Segmented Lucy Richardson'};
            app.PVCimplementedDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PVCimplementedDropDown_2ValueChanged, true);
            app.PVCimplementedDropDown_2.Value = 'Select One';
            app.SelectdirectoryButton_3 = uibutton(app.RegionalQuantificationTab, 'push');
            app.SelectdirectoryButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectdirectoryButton_3Pushed, true);
            app.SelectdirectoryButton_3.Text = 'Select directory';
            app.AtlasButton = uibutton(app.RegionalQuantificationTab, 'push');
            app.AtlasButton.ButtonPushedFcn = createCallbackFcn(app, @AtlasButtonPushed, true);
            app.AtlasButton.Text = 'Atlas';
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.VisualizationPanel.Visible = 'off';
            app.UIAxes1 = uiaxes(app.VisualizationPanel);
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes1.ButtonDownFcn = createCallbackFcn(app, @UIAxes1ButtonDown, true);
            app.SelectdirectoryButton = uibutton(app.VisualizationPanel, 'push');
            app.SelectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirectoryButtonPushed2, true);
            app.SelectdirectoryButton.Text = 'Select directory';
            app.pathLabel = uilabel(app.VisualizationPanel);
            app.pathLabel.Enable = 'off';
            app.pathLabel.Text = '*path';
            app.Label_2 = uilabel(app.VisualizationPanel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.VisualizationPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.VisualizationPanel);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.VisualizationPanel);
            app.Label_5.Text = '';
            app.TitleEditFieldLabel = uilabel(app.VisualizationPanel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.VisualizationPanel, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.VisualizeButton = uibutton(app.VisualizationPanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.PartialVolumeCorrectionMethodsPanel = uipanel(app.UIFigure);
            app.PartialVolumeCorrectionMethodsPanel.Title = 'Partial Volume Correction Methods';
            app.PartialVolumeCorrectionMethodsPanel.Visible = 'off';
            app.MullerGartnerButton = uibutton(app.PartialVolumeCorrectionMethodsPanel, 'push');
            app.MullerGartnerButton.ButtonPushedFcn = createCallbackFcn(app, @MullerGartnerButtonPushed2, true);
            app.MullerGartnerButton.Text = 'Muller Gartner';
            app.LucyRichardsonButton = uibutton(app.PartialVolumeCorrectionMethodsPanel, 'push');
            app.LucyRichardsonButton.ButtonPushedFcn = createCallbackFcn(app, @LucyRichardsonButtonPushed, true);
            app.LucyRichardsonButton.Text = 'Lucy Richardson';
            app.RoussetButton = uibutton(app.PartialVolumeCorrectionMethodsPanel, 'push');
            app.RoussetButton.ButtonPushedFcn = createCallbackFcn(app, @RoussetButtonPushed, true);
            app.RoussetButton.Text = 'Rousset';
            app.PARSLRButton = uibutton(app.PartialVolumeCorrectionMethodsPanel, 'push');
            app.PARSLRButton.ButtonPushedFcn = createCallbackFcn(app, @PARSLRButtonPushed, true);
            app.PARSLRButton.Text = 'PARSLR';
            app.HelpPanel = uipanel(app.UIFigure);
            app.HelpPanel.Title = 'Help';
            app.Image2 = uiimage(app.HelpPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Images', 'Help', 'Help.png');
            app.Hyperlink = uihyperlink(app.HelpPanel);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.URL = 'https://github.com/Partial-Volume-Correction-Toolbox/Partial-Volume-Correction-Toolbox/blob/main/Partial%20Volume%20Correction%20Toolbox.pdf';
            app.Hyperlink.Text = 'For more detailed information';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end