classdef lucy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PartialVolumeCorrectionToolboxPanel  matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        IterationsSlider                matlab.ui.control.Slider
        IterationsSliderLabel           matlab.ui.control.Label
        BinarizingThresholdSlider       matlab.ui.control.Slider
        BinarizingThresholdSliderLabel  matlab.ui.control.Label
        InitialsofMRIimageEditField     matlab.ui.control.EditField
        InitialsofMRIimageEditFieldLabel  matlab.ui.control.Label
        InitialsofPETimageEditField     matlab.ui.control.EditField
        InitialsofPETimageEditFieldLabel  matlab.ui.control.Label
        zmmEditField                    matlab.ui.control.NumericEditField
        zmmEditFieldLabel               matlab.ui.control.Label
        ymmEditField                    matlab.ui.control.NumericEditField
        ymmEditFieldLabel               matlab.ui.control.Label
        xmmEditField                    matlab.ui.control.NumericEditField
        xmmEditFieldLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RUNButton                       matlab.ui.control.Button
        iter                            matlab.ui.control.NumericEditField
        binTh                           matlab.ui.control.NumericEditField
        ScannerResolutionLabel          matlab.ui.control.Label
        pathLabel                       matlab.ui.control.Label
        SelectdirectoryButton           matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        LucyRichardsonLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        PathFolder
        PSFx
        PSFy
        PSFz
        binTH
        iterations
        pName
        mName
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app1
            delete(app)
        end

        % Button pushed function: SelectdirectoryButton
        function SelectdirectoryButtonPushed(app, event)
            figure(app.UIFigure);
            app.PathFolder=uigetdir();
            figure(app.UIFigure);
            if app.PathFolder ~= 0 
                app.pathLabel.Text = app.PathFolder;
            end
            
        end

        % Value changed function: InitialsofPETimageEditField
        function InitialsofPETimageEditFieldValueChanged(app, event)
            value = app.InitialsofPETimageEditField.Value;
            app.pName = value;
        end

        % Value changed function: InitialsofMRIimageEditField
        function InitialsofMRIimageEditFieldValueChanged(app, event)
            value = app.InitialsofMRIimageEditField.Value;
            app.mName = value;
        end

        % Value changed function: xmmEditField
        function xmmEditFieldValueChanged(app, event)
            value = app.xmmEditField.Value;
            app.PSFx = value;
        end

        % Value changed function: ymmEditField
        function ymmEditFieldValueChanged(app, event)
            value = app.ymmEditField.Value;
            app.PSFy = value;
        end

        % Value changed function: zmmEditField
        function zmmEditFieldValueChanged(app, event)
            value = app.zmmEditField.Value;
            app.PSFz = value;
        end

        % Value changed function: BinarizingThresholdSlider
        function BinarizingThresholdSliderValueChanged(app, event)
            value = app.BinarizingThresholdSlider.Value;
            app.binTh.Value = value;
            app.binTH = value;
        end

        % Value changed function: binTh
        function binThValueChanged(app, event)
            value = app.binTh.Value;
            app.BinarizingThresholdSlider.Value = value;
            app.binTH = value;
        end

        % Value changed function: IterationsSlider
        function IterationsSliderValueChanged(app, event)
            value = app.IterationsSlider.Value;
            app.iter.Value = fix(value);
            app.iterations = fix(value);
        end

        % Value changed function: iter
        function iterValueChanged(app, event)
            value = app.iter.Value;
            app.IterationsSlider.Value = value;
            app.iterations = value;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            pathFolder=app.PathFolder;
            psfx=app.PSFx;
            psfy=app.PSFy;
            psfz=app.PSFz;
            BinTh=app.binTH;
            Iteration=app.iterations;
            PName=app.pName;
            MRIName=app.mName;

            if isempty(psfx) || isempty(psfy) || isempty(psfz) || isempty(pathFolder)...
               || isempty(BinTh) || isempty(Iteration) || isequal(pathFolder,0) ...
               || isempty(PName) || isempty(MRIName)
               clc
               msgbox('There is not enough input for initiating Partial Volume Correction', ...
                'Partial Volume Correction', 'warn');
               return;
            elseif BinTh>1 || BinTh<=0
                clc
               msgbox('Binarizing Threshold Should Be Between 0 and 1', ...
                'Partial Volume Correction', 'warn');
               return
            elseif floor(Iteration)~=Iteration
                clc
               msgbox('Iteration Number Should Be a Positive Integer', ...
                'Partial Volume Correction', 'warn');
               return
            elseif psfx<=0 || psfy<=0 || psfz<=0
                clc
                msgbox('Scanner PSF is a positive value', ...
                'Partial Volume Correction', 'warn');
               return
            end
            %% Reading files of subdirectory

            d = dir(pathFolder);
            isub = [d(:).isdir]; %# returns logical vector
            dirlist = {d(isub).name}';
            dirlist(1:2) = [];
            if isempty(dirlist)
             box=msgbox({'There is no subdirectory in Pathfolder '},'Partial Volume Correction','warn');
               close(gcbf)
               return
            end   
            kk=1:length(dirlist);
            Variables=whos;
            close all
            save Variables
            %% SPM Defaults
            clc
            spm_get_defaults;
            spm_jobman('initcfg')
            
            clc
            %% PVC
            for kk=1:length(dirlist)
                wb=waitbar(0.1,['Loading files from ' dirlist{kk}],'Name', 'LR Partial Volume Correction') ;     
            
            pause(10)
            if ishandle(wb)
                MRIfile=dir([pathFolder '/' dirlist{kk} '/' MRIName,'*.nii']);
                MRIpath=[pathFolder '/' dirlist{kk} '/' MRIfile.name];
            
                PETfile=dir([pathFolder '/' dirlist{kk} '/' PName,'*.nii']);
                PETpath=[pathFolder '/' dirlist{kk} '/' PETfile(1).name]
                
                %Copy source file
            mkdir([pathFolder '/' dirlist{kk}],'Sourcefiles')
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
            for pp=1:length(filepattern)
                copyfile([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name],[pathFolder '/' dirlist{kk} '/Sourcefiles'])
            end
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*_seg8.mat') );
            if ~isempty(filepattern)    
            for tt=1:length(filepattern)    
                copyfile([pathFolder '/'  dirlist{kk} '/'  filepattern(tt).name],[pathFolder '/' dirlist{kk} '/Sourcefiles'])
            end
            end     
            
            %Checking the segmented file
            
            GMcheck=dir([pathFolder '/' dirlist{kk} '/c1' MRIfile.name]);
            WMcheck=dir([pathFolder '/' dirlist{kk} '/c2' MRIfile.name]);
            CSFcheck=dir([pathFolder '/' dirlist{kk} '/c3' MRIfile.name]);
            Fieldcheck=dir([pathFolder '/' dirlist{kk} '/y_' MRIfile.name]);
            InvFcheck=dir([pathFolder '/' dirlist{kk} '/iy_' MRIfile.name]);
            wb=waitbar(0.2,wb,['Segmentation for ' dirlist{kk}]) ;
            else
                clc
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Lucy Richardson Partial Volume Correction','warn');
                break
            end
            
            pause(30)
            if ishandle(wb)
            if isempty(GMcheck) || isempty(WMcheck)   || isempty(CSFcheck)...
               || isempty(Fieldcheck)  || isempty(InvFcheck)   
                 
            %do the segmentation
            
                matlabbatch{1}.spm.spatial.preproc.channel.vols ={[MRIpath,',',int2str(1)]};
                matlabbatch{1}.spm.spatial.preproc.channel.biasreg = 0.0001;
                matlabbatch{1}.spm.spatial.preproc.channel.biasfwhm = 60;
                matlabbatch{1}.spm.spatial.preproc.channel.write = [0 0];
                matlabbatch{1}.spm.spatial.preproc.tissue(1).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(1)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(1).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(1).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(1).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(2).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(2)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(2).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(2).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(2).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(3).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(3)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(3).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(3).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(3).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(4).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(4)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(4).ngaus = 3;
                matlabbatch{1}.spm.spatial.preproc.tissue(4).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(4).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(5).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(5)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(5).ngaus = 4;
                matlabbatch{1}.spm.spatial.preproc.tissue(5).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(5).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(6).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(6)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(6).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(6).native = [0 0];
                matlabbatch{1}.spm.spatial.preproc.tissue(6).warped = [0 0];
                matlabbatch{1}.spm.spatial.preproc.warp.mrf = 1;
                matlabbatch{1}.spm.spatial.preproc.warp.cleanup = 1;
                matlabbatch{1}.spm.spatial.preproc.warp.reg = [0 0.001 0.5 0.05 0.2];
                matlabbatch{1}.spm.spatial.preproc.warp.affreg = 'mni';
                matlabbatch{1}.spm.spatial.preproc.warp.fwhm = 0;
                matlabbatch{1}.spm.spatial.preproc.warp.samp = 3;
                matlabbatch{1}.spm.spatial.preproc.warp.write = [1 1];
                
                spm_jobman('run', matlabbatch);
                clc
                clear matlabbatch
            %Segmented files 
            
            GMpath=[pathFolder '/' dirlist{kk} '/c1' MRIfile.name];
            WMpath=[pathFolder '/' dirlist{kk} '/c2' MRIfile.name];
            CSFpath=[pathFolder '/' dirlist{kk} '/c3' MRIfile.name];
            else
                
                GMpath=[pathFolder '/' dirlist{kk} '/' GMcheck.name];
                WMpath=[pathFolder '/' dirlist{kk}  '/' WMcheck.name];
                CSFpath=[pathFolder '/' dirlist{kk}  '/' CSFcheck.name];
            end
            else
                clc
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Lucy Richardson Partial Volume Correction','warn');
                break
            end
            wb=waitbar(0.3,wb,['Preprocessing Segmented GM & WM from  ' dirlist{kk}]);
            
            pause(5)
            if ishandle(wb)
            GM_nii=load_untouch_nii(GMpath);    
            GMSegmnt = spm_read_vols(spm_vol(GMpath));
            GM=zeros(size(GMSegmnt));
            GM(GMSegmnt>BinTh)=3;
            %Binarizing WM
            WMSegmnt = spm_read_vols(spm_vol(WMpath));
            WM=zeros(size(WMSegmnt));
            WM(WMSegmnt>0.5 & GM==0)=2;
            %Binarizing CSf
            CSFSegmnt = spm_read_vols(spm_vol(CSFpath));
            CSF=zeros(size(CSFSegmnt));
            CSF(WM==0 & GM==0 & CSFSegmnt>0)=1;
            Mask=GM+CSF+WM;

            HDR = spm_vol(WMpath);
            hdr = spm_vol(GMpath);

            hdr.fname=['Mask_tr' num2str(BinTh) '.nii'];
            hdr.dim = size(Mask);
            hdr.dt = [4,1];
            hdr.mat = HDR.mat;
            spm_write_vol(hdr,Mask);
            newname = [ 'Mask_tr' num2str(BinTh) '.nii'];
            

            CurrentDir=pwd;
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
            movefile(newname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1
            movefile(newname,dirlist{kk})   
            end
            clear nii
            else
                clc
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                       copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')   
                clc    
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'Lucy Richardson Partial Volume Correction','warn');
                break
            end
            wb=waitbar(0.4,wb,['Processing files from  ' dirlist{kk}]) ;
            
            %% Reslice
            pause(10)
            if ishandle(wb)
                
            PETvol={[PETpath,',',int2str(1)]};
            Maskpath=[pathFolder '/' dirlist{kk}  '/' newname];
            Maskvol={[Maskpath,',',int2str(1)]};
            
            clear matlabbatch
            matlabbatch{1}.spm.spatial.coreg.estimate.ref = PETvol;
            matlabbatch{1}.spm.spatial.coreg.estimate.source = Maskvol;
            
            spm_jobman('run', matlabbatch);
            clc
            clear matlabbatch
            else
                clc     
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                       copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end
                 rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                 box=msgbox({'Operation Cancled for '; dirlist{kk}},'Lucy Richardson Partial Volume Correction','warn');
                break
            end
            
            wb=waitbar(0.5,wb,['Preprocessing files from  ' dirlist{kk}]) ;
            pause(5)
            if ishandle(wb)
            % Reslice
            
            Image{1} = PETpath;
            Image{2} = Maskpath;
            
            flags = struct('mask',1,'interp',0,'mean',0,'which',1,'wrap',[0 0 0]);
            P = {Image{1:2}}';
            
            spm_reslice(P,flags)
            clc
            clear matlabbatch
            
            else
                clc  
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii' ));
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                
                    filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end  
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Lucy Richardson Partial Volume Correction','warn');
                break
            end
            
            wb=waitbar(0.6,wb,['Runing PVC for  ' dirlist{kk}]) ;  
            pause(5)
            if  ishandle(wb)
            
                HDR = spm_vol(PETpath);
                PET = spm_read_vols(HDR);
            
                voxel_size_x = sqrt(sum(HDR.mat(1:3, 1).^2));
                voxel_size_y = sqrt(sum(HDR.mat(1:3, 2).^2));
                voxel_size_z = sqrt(sum(HDR.mat(1:3, 3).^2));
                PET_Voxel_size = [voxel_size_x, voxel_size_y, voxel_size_z];
            
                rMaskpath=[pathFolder '/' dirlist{kk}  '/r' newname];
                rMask=spm_read_vols(spm_vol(rMaskpath));
                %PSF
            A=zeros(9,9,9);
            A(5,5,5)=1;
            PSFx=smooth3(A,'gaussian',[9 1 1],psfx./(2.35.*PET_Voxel_size(1)));
            PSFxy=smooth3(PSFx,'gaussian',[1 9 1],psfy./(2.35.*PET_Voxel_size(2)));
            PSF=smooth3(PSFxy,'gaussian',[1 1 9],psfz./(2.35.*PET_Voxel_size(3)));
            FT_PSF=psf2otf(PSF,size(PET));
            Last_estimate=PET;
                wb=waitbar(0.65,wb,['Loading files for  ' dirlist{kk}]) ;
            else
                clc  
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii' ));
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                
                    filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
                
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end  
               
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Lucy Richardson Partial Volume Correction','warn');
                break
            end
            wb=waitbar(0.7,wb,['Processing files from  ' dirlist{kk}]) ;
            pause(10)
            if ishandle(wb)
             for i=1:Iteration
                 i
                 FT_Last_estimate=fftn(Last_estimate);
                Smooth=(ifftn(FT_Last_estimate.*FT_PSF));
                Smooth(Smooth<0.000001)=0;
                %Compare with Observed
                Smooth = double(Smooth);
                Ratio=PET./Smooth;
                Ratio(isnan(Ratio))=0;
                Ratio(isinf(Ratio))=0; 
                %Fourier Transform
                FT_Ratio=fftn(Ratio);
                %Smooth Ratio
                W=ifftn(FT_Ratio.*FT_PSF);
                %Update Estimate
                Last_estimate = double(Last_estimate);
                W = double(W);
                Last_estimate=Last_estimate.*W;
                
                
             end
            wb=waitbar(0.7,wb,['Loading files for  ' dirlist{kk}]) ;
            else
                clc  
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii' ));
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                
                    filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
                
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end    
                
              
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Lucy Richardson Partial Volume Correction','warn');
                break
            end
            wb=waitbar(0.75,wb,['Processing files from  ' dirlist{kk}]) ;
            pause(10)
            if ishandle(wb)

            HDR = spm_vol(PETpath);
            hdr.fname=[PETfile(1).name(1:end-4),'_LRPVC_Itr', num2str(Iteration), '.nii'];
            hdr.dim = size(Last_estimate);
            hdr.dt = [4,1];
            hdr.mat = HDR.mat;
            spm_write_vol(hdr,Last_estimate);


            newname=[PETfile(1).name(1:end-4),'_LRPVC_Itr', num2str(Iteration), '.nii'];
            CurrentDir=pwd;
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
               movefile(newname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1
             movefile(newname,dirlist{kk})   
            end
            wb=waitbar(0.9,wb,['Done Partial Volume Correction for  ' dirlist{kk}]) ;
            else
             clc  
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii' ));
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
                
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end      
            
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Lucy Richardson Partial Volume Correction','warn');
                break
            end
            wb=waitbar(0.95,wb,['Saving corrected file for  ' dirlist{kk}]) ;   
            
            pause(5)
            if ishandle(wb)
               rGM=zeros(size(rMask));
               rGM(rMask==3)=1;
               extract_GM=(Last_estimate.*rGM);
               newname=['GM_' PETfile(1).name(1:end-4),'_LRPVC_Itr', num2str(Iteration), '.nii'];

               HDR = spm_vol(PETpath);
               hdr.fname=['GM_' PETfile(1).name(1:end-4),'_LRPVC_Itr', num2str(Iteration), '.nii'];
               hdr.dim = size(extract_GM);
               hdr.dt = [4,1];
               hdr.mat = HDR.mat;
               spm_write_vol(hdr,extract_GM);

            CurrentDir=pwd;
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
               movefile(newname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1
             movefile(newname,dirlist{kk})   
            end
            else
             clc  
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii' ));
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                
                    filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
                
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end      
               
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Lucy Richardson Partial Volume Correction','warn');
                break
            end
            wb=waitbar(1,wb,['Done PVC for  ' dirlist{kk}]) ;
            if  kk~=length(dirlist) && ishandle(wb)
            delete(wb)
            clear all
            load Variables.mat;
            clc
            spm_get_defaults;
            spm_jobman('initcfg')
            elseif kk==length(dirlist) && ishandle(wb)
            savefig(wb,'wb')
            else
               clc   
                   filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                       copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            
            if ~isempty(filepattern)    
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end      
            
            rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
            box=msgbox({'Operation Cancelled for '; pathFolder},'Muller Gartner Partial Volume Correction','warn');    
                break
            end
            end
            %% Output
            O1={['Lucy Richardson PVC is done for ' pathFolder]};
            O2={['Scanner point spread function is ' num2str(psfx) ' '  num2str(psfy) ' ' num2str(psfz)]};
            O3={['Binarizing Threshold is ' num2str(BinTh)]};
            O4={['Initials of PET is ' PName]};
            O5={['Initials of MRI is ' MRIName]};
            O6={['Iteration number is ' num2str(Iteration)]};
            fid=fopen([pathFolder '/LRPVC_parameters.txt'],'w');
            fprintf(fid,'%s\n',O1{:});
            fprintf(fid,'%s\n',O2{:});
            fprintf(fid,'%s\n',O3{:});
            fprintf(fid,'%s\n',O4{:});
            fprintf(fid,'%s\n',O5{:});
            fprintf(fid,'%s\n',O6{:});

            app.pathLabel.Text = ' ';
            app.xmmEditField.Value= 0;
            app.ymmEditField.Value= 0;
            app.zmmEditField.Value= 0;
            app.InitialsofMRIimageEditField.Value = '';
            app.InitialsofPETimageEditField.Value = '';
            app.binTh.Value = 0;
            app.iter.Value = 0;
            app.BinarizingThresholdSlider.Value = 0;
            app.IterationsSlider.Value = 0;

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            lucyINFO
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PartialVolumeCorrectionToolboxPanel = uipanel(app.UIFigure);
            app.PartialVolumeCorrectionToolboxPanel.Title = 'Partial Volume Correction Toolbox';
            app.LucyRichardsonLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.LucyRichardsonLabel.Text = 'Lucy Richardson';
            app.BackButton = uibutton(app.PartialVolumeCorrectionToolboxPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.SelectdirectoryButton = uibutton(app.PartialVolumeCorrectionToolboxPanel, 'push');
            app.SelectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirectoryButtonPushed, true);
            app.SelectdirectoryButton.Text = 'Select directory';
            app.pathLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.pathLabel.Enable = 'off';
            app.pathLabel.Text = '*path';
            app.ScannerResolutionLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.ScannerResolutionLabel.Text = 'Scanner Resolution:';
            app.binTh = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.binTh.Limits = [0 1];
            app.binTh.ValueDisplayFormat = '%.2f';
            app.binTh.ValueChangedFcn = createCallbackFcn(app, @binThValueChanged, true);
            app.iter = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.iter.ValueDisplayFormat = '%.0f';
            app.iter.ValueChangedFcn = createCallbackFcn(app, @iterValueChanged, true);
            app.RUNButton = uibutton(app.PartialVolumeCorrectionToolboxPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Image = uiimage(app.PartialVolumeCorrectionToolboxPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'MullerGartner', 'info.png');
            app.xmmEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.xmmEditFieldLabel.Text = 'x (mm)';
            app.xmmEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.xmmEditField.ValueChangedFcn = createCallbackFcn(app, @xmmEditFieldValueChanged, true);
            app.ymmEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.ymmEditFieldLabel.Text = 'y (mm)';
            app.ymmEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.ymmEditField.ValueChangedFcn = createCallbackFcn(app, @ymmEditFieldValueChanged, true);
            app.zmmEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.zmmEditFieldLabel.Text = 'z (mm)';
            app.zmmEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.zmmEditField.ValueChangedFcn = createCallbackFcn(app, @zmmEditFieldValueChanged, true);
            app.InitialsofPETimageEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.InitialsofPETimageEditFieldLabel.Text = 'Initials of PET image:';
            app.InitialsofPETimageEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'text');
            app.InitialsofPETimageEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsofPETimageEditFieldValueChanged, true);
            app.InitialsofMRIimageEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.InitialsofMRIimageEditFieldLabel.Text = 'Initials of MRI image:';
            app.InitialsofMRIimageEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'text');
            app.InitialsofMRIimageEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsofMRIimageEditFieldValueChanged, true);
            app.BinarizingThresholdSliderLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.BinarizingThresholdSliderLabel.Text = 'Binarizing Threshold';
            app.BinarizingThresholdSlider = uislider(app.PartialVolumeCorrectionToolboxPanel);
            app.BinarizingThresholdSlider.Limits = [0 1];
            app.BinarizingThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinarizingThresholdSliderValueChanged, true);
            app.IterationsSliderLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.IterationsSliderLabel.Text = 'Iterations';
            app.IterationsSlider = uislider(app.PartialVolumeCorrectionToolboxPanel);
            app.IterationsSlider.Limits = [1 100];
            app.IterationsSlider.MajorTicks = [1 20 40 60 80 100];
            app.IterationsSlider.MajorTickLabels = {'1', '20', '40', '60', '80', '100'};
            app.IterationsSlider.ValueChangedFcn = createCallbackFcn(app, @IterationsSliderValueChanged, true);
            app.IterationsSlider.MinorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.IterationsSlider.Value = 1;
            app.Label = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lucy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end