classdef muller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PartialVolumeCorrectionToolboxPanel  matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RUNButton                       matlab.ui.control.Button
        regTh                           matlab.ui.control.NumericEditField
        binTh                           matlab.ui.control.NumericEditField
        RegressionThresholdSlider       matlab.ui.control.Slider
        RegressionThresholdSliderLabel  matlab.ui.control.Label
        BinarizingThresholdSlider       matlab.ui.control.Slider
        BinarizingThresholdSliderLabel  matlab.ui.control.Label
        InitialsofMRIimageEditField     matlab.ui.control.EditField
        InitialsofMRIimageEditFieldLabel  matlab.ui.control.Label
        InitialsofPETimageEditField     matlab.ui.control.EditField
        InitialsofPETimageLabel         matlab.ui.control.Label
        zmmEditField                    matlab.ui.control.NumericEditField
        zmmEditFieldLabel               matlab.ui.control.Label
        ymmEditField                    matlab.ui.control.NumericEditField
        ymmEditFieldLabel               matlab.ui.control.Label
        xmmEditField                    matlab.ui.control.NumericEditField
        xmmEditFieldLabel               matlab.ui.control.Label
        ScannerResolutionLabel          matlab.ui.control.Label
        pathLabel                       matlab.ui.control.Label
        SelectdirectoryButton           matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        MullerGartnerLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        PathFolder
        PSFx
        PSFy
        PSFz
        binTH
        regTH
        pName
        mName
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed2(app, event)
            app1
            delete(app)
        end

        % Button pushed function: SelectdirectoryButton
        function SelectdirectoryButtonPushed2(app, event)
            figure(app.UIFigure);
            app.PathFolder=uigetdir();
            figure(app.UIFigure);
            if app.PathFolder~=0
                app.pathLabel.Text = app.PathFolder;
            end
            
        end

        % Value changed function: BinarizingThresholdSlider
        function BinarizingThresholdSliderValueChanged(app, event)
            value = app.BinarizingThresholdSlider.Value;
            app.binTh.Value = value;
            app.binTH = value;
            
        end

        % Value changed function: RegressionThresholdSlider
        function RegressionThresholdSliderValueChanged(app, event)
            value = app.RegressionThresholdSlider.Value;
            app.regTh.Value = value;
            app.regTH = value;
        end

        % Value changed function: binTh
        function binThValueChanged(app, event)
            value = app.binTh.Value;
            app.BinarizingThresholdSlider.Value = value;
            app.binTH = value;
        end

        % Value changed function: regTh
        function regThValueChanged(app, event)
            value = app.regTh.Value;
            app.RegressionThresholdSlider.Value = value;
            app.regTH = value;
        end

        % Value changed function: InitialsofPETimageEditField
        function InitialsofPETimageEditFieldValueChanged(app, event)
            value = app.InitialsofPETimageEditField.Value;
            app.pName = value;
        end

        % Value changed function: InitialsofMRIimageEditField
        function InitialsofMRIimageEditFieldValueChanged(app, event)
            value = app.InitialsofMRIimageEditField.Value;
            app.mName = value;
        end

        % Value changed function: xmmEditField
        function xmmEditFieldValueChanged(app, event)
            value = app.xmmEditField.Value;
            app.PSFx = value;
        end

        % Value changed function: ymmEditField
        function ymmEditFieldValueChanged(app, event)
            value = app.ymmEditField.Value;
            app.PSFy = value;
        end

        % Value changed function: zmmEditField
        function zmmEditFieldValueChanged(app, event)
            value = app.zmmEditField.Value;
            app.PSFz = value;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)

            pathFolder=app.PathFolder;
            psfx=app.PSFx;
            psfy=app.PSFy;
            psfz=app.PSFz;
            BinTh=app.binTH;
            RegTh=app.regTH;
            PName=app.pName;
            MName=app.mName;


            if isempty(psfx) || isempty(psfy) || isempty(psfz) || isempty(pathFolder)...
               || isempty(BinTh) || isempty(RegTh) || isequal(pathFolder,0) ...
               || isempty(PName) || isempty(MName)
               clc
               msgbox('There is not enough input for initiating Partial Volume Correction', ...
                'Partial Volume Correction', 'warn');
               return;
            elseif BinTh>1 || BinTh<=0
                clc
             msgbox('Binarizing Threshold Should Be Between 0 and 1', ...
                'Partial Volume Correction', 'warn');
               return
            elseif RegTh<0.9 || RegTh>0.99
                clc
                msgbox('Regression Threshold Should Be Between 0.9 and 0.99', ...
                'Partial Volume Correction', 'warn');
               return
            elseif psfx<=0 || psfy<=0 || psfz<=0
                clc
                msgbox('Scanner PSF is a positive value', ...
                'Partial Volume Correction', 'warn');
               return
            end

            %% Reading files of subdirectory
            d = dir(pathFolder);
            isub = [d(:).isdir]; %# returns logical vector
            dirlist = {d(isub).name}';
            dirlist(1:2) = [];

            if isempty(dirlist)
               box=msgbox('There is no subdirectory in Pathfolder ','Partial Volume Correction','warn');
               close(gcbf)
               return
            end 
            kk=1:length(dirlist);

            Variables=whos;
            save Variables

            %% SPM Defaults

            spm_get_defaults;
            spm_jobman('initcfg')

            clc

            %% 
            for kk=1:length(dirlist)
            wb=waitbar(0.1,['Loading  ' dirlist{kk}],'Name', 'Muller Gartner Partial Volume Correction') ;  
            
            pause(10)
            if ishandle(wb)

            MRIfile=dir([pathFolder '/' dirlist{kk} '/' MName,'*.nii']);
            MRIpath=[pathFolder '/' dirlist{kk} '/' MRIfile.name];


            PETfile=dir([pathFolder '/' dirlist{kk} '/' PName,'*.nii']);
            PETpath=[pathFolder '/' dirlist{kk} '/' PETfile.name];

            %Copy source file
            mkdir([pathFolder '/' dirlist{kk}],'Sourcefiles')
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
            for pp=1:length(filepattern)
                copyfile([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name],[pathFolder '/' dirlist{kk} '/Sourcefiles'])
            end

            filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.mat') );
            if ~isempty(filepattern)
                for tt=1:length(filepattern)
                copyfile([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name],[pathFolder '/' dirlist{kk} '/Sourcefiles'])
                end
            end

            %Checking the segmented file

            GMcheck=dir([pathFolder '/' dirlist{kk} '/c1' MRIfile.name]);
            WMcheck=dir([pathFolder '/' dirlist{kk} '/c2' MRIfile.name]);
            wb=waitbar(0.2,wb,['Segmentation for ' dirlist{kk}]) ;
            else
                clc
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Muller Gartner Partial Volume Correction','warn');
                break
            end

            pause(30)
            if ishandle(wb)
            if isempty(GMcheck) || isempty(WMcheck)   

            %do the segmentation

                matlabbatch{1}.spm.spatial.preproc.channel.vols ={[MRIpath,',',int2str(1)]};
                matlabbatch{1}.spm.spatial.preproc.channel.biasreg = 0.0001;
                matlabbatch{1}.spm.spatial.preproc.channel.biasfwhm = 60;
                matlabbatch{1}.spm.spatial.preproc.channel.write = [0 0];
                matlabbatch{1}.spm.spatial.preproc.tissue(1).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(1)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(1).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(1).native = [1 1];
                    matlabbatch{1}.spm.spatial.preproc.tissue(1).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(2).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(2)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(2).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(2).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(2).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(3).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(3)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(3).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(3).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(3).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(4).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(4)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(4).ngaus = 3;
                matlabbatch{1}.spm.spatial.preproc.tissue(4).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(4).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(5).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(5)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(5).ngaus = 4;
                matlabbatch{1}.spm.spatial.preproc.tissue(5).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(5).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(6).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(6)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(6).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(6).native = [0 0];
                matlabbatch{1}.spm.spatial.preproc.tissue(6).warped = [0 0];
                matlabbatch{1}.spm.spatial.preproc.warp.mrf = 1;
                matlabbatch{1}.spm.spatial.preproc.warp.cleanup = 1;
                matlabbatch{1}.spm.spatial.preproc.warp.reg = [0 0.001 0.5 0.05 0.2];
                matlabbatch{1}.spm.spatial.preproc.warp.affreg = 'mni';
                matlabbatch{1}.spm.spatial.preproc.warp.fwhm = 0;
                matlabbatch{1}.spm.spatial.preproc.warp.samp = 3;
                matlabbatch{1}.spm.spatial.preproc.warp.write = [1 1];

                spm_jobman('run', matlabbatch);
                clc
            %Segmented files 

            GMpath=[pathFolder '/' dirlist{kk} '/c1' MRIfile.name];
            WMpath=[pathFolder '/' dirlist{kk} '/c2' MRIfile.name];

            else

                GMpath=[pathFolder '/' dirlist{kk} '/' GMcheck.name];
                WMpath=[pathFolder '/' dirlist{kk}  '/' WMcheck.name];

            end
            else
                clc
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Muller Gartner Partial Volume Correction','warn');
                break
            end

            wb=waitbar(0.4,wb,['Preprocessing Segmented GM & WM for  ' dirlist{kk}]) ; 

            %% Reslice to PET space
            pause(15)
            if ishandle(wb)

            PETvol={[PETpath,',',int2str(1)]};
            MRIvol={[MRIpath,',',int2str(1)]};

            %GM_Mask
            GMvol={[GMpath,',',int2str(1)]};

            %WM_Mask
            WMvol={[WMpath,',',int2str(1)]};

            others=[GMvol WMvol]';

            clear matlabbatch
            matlabbatch{1}.spm.spatial.coreg.estimate.ref = PETvol;
            matlabbatch{1}.spm.spatial.coreg.estimate.source = MRIvol;
            matlabbatch{1}.spm.spatial.coreg.estimate.other = others;

            spm_jobman('run', matlabbatch);
            clc
            else
                clc     
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end

                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                       copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end

                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
               delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end
                 rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                 box=msgbox({'Operation Cancled for '; dirlist{kk}},'Muller Gartner Partial Volume Correction','warn');
                break
            end

            wb=waitbar(0.5,wb,['Preprocessing Segmented GM & WM for  ' dirlist{kk}]) ;
            pause(15)
            if ishandle(wb)
            % Reslice

            Image{1} = PETpath;
            Image{2} = MRIpath;
            Image{3} = WMpath;
            Image{4} = GMpath;

            flags = struct('mask',1,'interp',0,'mean',0,'which',1,'wrap',[0 0 0], 'prefix','r1');
            P = {Image{1:4}}';

            spm_reslice(P,flags)
            clc
            clear matlabbatch

            else
                clc  
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii' ));
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end

                    filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'],'*.mat') );
            if ~isempty(filepattern)
                for tt=1:length(filepattern)  
                delete([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
                end
                else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end

                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Muller Gartner Partial Volume Correction','warn');
                break
            end

            wb=waitbar(0.6,wb,['Runing PVC for  ' dirlist{kk}]) ;  

            %% Muller Gartner PVC 
            pause(20)
            if ishandle(wb)

            HDR = spm_vol(PETpath);
            PET = spm_read_vols(HDR);

            %Voxel Dimension
            rGMpath=[pathFolder '/' dirlist{kk} '/r1c1' MRIfile.name];
            rWMpath=[pathFolder '/' dirlist{kk} '/r1c2' MRIfile.name];

            %PSF
            A=zeros(9,9,9);
            A(5,5,5)=1;

            voxel_size_x = sqrt(sum(HDR.mat(1:3, 1).^2));
            voxel_size_y = sqrt(sum(HDR.mat(1:3, 2).^2));
            voxel_size_z = sqrt(sum(HDR.mat(1:3, 3).^2));
            PET_Voxel_size = [voxel_size_x, voxel_size_y, voxel_size_z];

            PSFX=smooth3(A,'gaussian',[9 1 1],psfx./(2.35.*PET_Voxel_size(1)));
            PSFxy=smooth3(PSFX,'gaussian',[1 9 1],psfy./(2.35.*PET_Voxel_size(2)));
            PSF=smooth3(PSFxy,'gaussian',[1 1 9],psfz./(2.35.*PET_Voxel_size(3)));
            FT_PSF=psf2otf(PSF,size(PET));

            %Binarizing GM
            GM_nii=load_untouch_nii(rGMpath);
            GMSegmnt = spm_read_vols(spm_vol(rGMpath));
            GM=zeros(size(GMSegmnt));
            GM(GMSegmnt>BinTh)=1;
            SGM=ifftn(fftn(GM).*FT_PSF);
            SGM(SGM<0.0001)=0;
            GM(SGM<0.2)=0;
            %Binarizing WM 
            WMSegmnt = spm_read_vols(spm_vol(rWMpath));
            WM=zeros(size(WMSegmnt));
            WM(WMSegmnt>0.5 & GM==0)=1;

            %Smoothing mask
            Mask=WM+GM;
            WM(SGM<0.2 & SGM>0)=1;
            WM(Mask==0)=0;
            SGM(SGM<0.2)=0;
            SWM=ifftn(fftn(WM).*FT_PSF);
            SWM(SWM<0)=0;

            %WM uptake
            R=RegTh;
            for n=1:round((1-R).*1000)    
                 Average(n)=mean(PET(SWM > R & SWM <= R+0.001));
                 R=R+0.001;
            end

            %Regression
            x=RegTh:0.001:1;
            Nan=find(isnan(Average));
            if ~isempty(Nan)
                y=Average(1:Nan-1);
                x=x(1:Nan-1);
                Regression_Coefficients=polyfit(x(1:end),y,1);
            else
                y=Average;
                x=RegTh:0.001:1;
                Regression_Coefficients=polyfit(x(2:end),y,1);
            end

            WM_Uptake=Regression_Coefficients(2).*x(end)+Regression_Coefficients(1);


            %Muller Gartner
            Corrected_GM=(PET-WM_Uptake.*SWM)./SGM;

            Corrected_GM(isnan(Corrected_GM))=0;
            Corrected_GM(isinf(Corrected_GM))=0;

            Corrected_Intensity=Corrected_GM.*GM;

            Corrected_Intensity(Corrected_Intensity<0)=0;
            else
                clc   
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii' ));
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end

                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                       copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern) 
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end

                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Muller Gartner Partial Volume Correction','warn');
                break
            end
            %% Save Images
            wb=waitbar(0.8,wb,['Saving Corrected Image in  ' dirlist{kk}]) ;  
            pause(10)
            if ishandle(wb)
            %Patient Mask
            HDR = spm_vol(rWMpath);
            hdr = spm_vol(rGMpath);
            hdr.fname=['rMask_tr', num2str(BinTh), '.nii'];
            PMask=2.*GM+WM;
            Mname=['rMask_tr', num2str(BinTh), '.nii'];

            hdr.dim = size(PMask);
            hdr.dt = [4,1];
            hdr.mat = HDR.mat;
            spm_write_vol(hdr,PMask);
            file = dir([pwd '/' Mname]);
            CurrentDir=pwd;
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
            movefile(file.name,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1 
             movefile(file.name,dirlist{kk})   
            end
            clear nii
            %PVC Image

            hdr.fname=[PETfile.name(1:end-4),'_MPVC_tr', num2str(BinTh), '.nii'];
            name=[PETfile.name(1:end-4),'_MPVC_tr', num2str(BinTh), '.nii'];
            hdr.dim = size(Corrected_Intensity);
            hdr.dt = [4,1];
            hdr.mat = HDR.mat;
            spm_write_vol(hdr,Corrected_Intensity);
            file = dir([pwd '/' name]);
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
            movefile(file.name,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1
             movefile(file.name,dirlist{kk})   
            end
            clear nii
            else
                clc
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                    filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                       copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern) 
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end
            rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
            box=msgbox({'Operation Cancled for '; dirlist{kk}},'Muller Gartner Partial Volume Correction','warn');    
                break
            end
            %% 
            wb=waitbar(1,wb,['Done Partial Volume Correction for  ' dirlist{kk}]) ;

            if  kk~=length(dirlist) && ishandle(wb)

            delete(wb)
            clear all
            load Variables.mat;
            clc
            spm_get_defaults;
            spm_jobman('initcfg')
            elseif kk==length(dirlist) && ishandle(wb)
                savefig(wb,'wb')
            else
               clc   
                   filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                       copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
                delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end
            rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
            box=msgbox({'Operation Cancelled for '; pathFolder},'Muller Gartner Partial Volume Correction','warn');    
                break
            end
            end
            %% Output
            O1={['Muller Gartner PVC is done for ' pathFolder]};
            O2={['Scanner point spread function is ' num2str(psfx) ' '  num2str(psfy) ' ' num2str(psfz)]};
            O3={['Binarizing Threshold is ' num2str(BinTh)]};
            O4={['Regression Threshold is ' num2str(RegTh)]};
            O5={['Initials of PET is ' PName]};
            O6={['Initials of MRI is ' MName]};
            fid=fopen([pathFolder '/MPVC_parameters.txt'],'w');
            fprintf(fid,'%s\n',O1{:});
            fprintf(fid,'%s\n',O2{:});
            fprintf(fid,'%s\n',O3{:});
            fprintf(fid,'%s\n',O4{:});
            fprintf(fid,'%s\n',O5{:});
            fprintf(fid,'%s\n',O6{:});
            %% 
  
            clc
            % Refreshing values
            app.regTh.Value = 0;
            app.binTh.Value = 0;
            app.BinarizingThresholdSlider.Value = 0;
            app.RegressionThresholdSlider.Value = 0.9;
            app.xmmEditField.Value = 0;
            app.ymmEditField.Value = 0;
            app.zmmEditField.Value = 0;
            app.InitialsofMRIimageEditField.Value = '';
            app.InitialsofPETimageEditField.Value = '';
            app.pathLabel.Text = '';


        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            mullerINFO
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PartialVolumeCorrectionToolboxPanel = uipanel(app.UIFigure);
            app.PartialVolumeCorrectionToolboxPanel.Title = 'Partial Volume Correction Toolbox';
            app.MullerGartnerLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.MullerGartnerLabel.Text = 'Muller Gartner';
            app.BackButton = uibutton(app.PartialVolumeCorrectionToolboxPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed2, true);
            app.BackButton.Text = 'Back';
            app.SelectdirectoryButton = uibutton(app.PartialVolumeCorrectionToolboxPanel, 'push');
            app.SelectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirectoryButtonPushed2, true);
            app.SelectdirectoryButton.Text = 'Select directory';
            app.pathLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.pathLabel.Enable = 'off';
            app.pathLabel.Text = '*path';
            app.ScannerResolutionLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.ScannerResolutionLabel.Text = 'Scanner Resolution:';
            app.xmmEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.xmmEditFieldLabel.Text = 'x (mm)';
            app.xmmEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.xmmEditField.ValueChangedFcn = createCallbackFcn(app, @xmmEditFieldValueChanged, true);
            app.ymmEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.ymmEditFieldLabel.Text = 'y (mm)';
            app.ymmEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.ymmEditField.ValueChangedFcn = createCallbackFcn(app, @ymmEditFieldValueChanged, true);
            app.zmmEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.zmmEditFieldLabel.Text = 'z (mm)';
            app.zmmEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.zmmEditField.ValueChangedFcn = createCallbackFcn(app, @zmmEditFieldValueChanged, true);
            app.InitialsofPETimageLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.InitialsofPETimageLabel.Text = 'Initials of PET image:';
            app.InitialsofPETimageEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'text');
            app.InitialsofPETimageEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsofPETimageEditFieldValueChanged, true);
            app.InitialsofMRIimageEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.InitialsofMRIimageEditFieldLabel.Text = 'Initials of MRI image:';
            app.InitialsofMRIimageEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'text');
            app.InitialsofMRIimageEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsofMRIimageEditFieldValueChanged, true);
            app.BinarizingThresholdSliderLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.BinarizingThresholdSliderLabel.Text = 'Binarizing Threshold';
            app.BinarizingThresholdSlider = uislider(app.PartialVolumeCorrectionToolboxPanel);
            app.BinarizingThresholdSlider.Limits = [0 1];
            app.BinarizingThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinarizingThresholdSliderValueChanged, true);
            app.RegressionThresholdSliderLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.RegressionThresholdSliderLabel.Text = 'Regression Threshold';
            app.RegressionThresholdSlider = uislider(app.PartialVolumeCorrectionToolboxPanel);
            app.RegressionThresholdSlider.Limits = [0.9 1];
            app.RegressionThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @RegressionThresholdSliderValueChanged, true);
            app.RegressionThresholdSlider.Value = 0.9;
            app.binTh = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.binTh.Limits = [0 1];
            app.binTh.ValueDisplayFormat = '%.2f';
            app.binTh.ValueChangedFcn = createCallbackFcn(app, @binThValueChanged, true);
            app.regTh = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.regTh.ValueDisplayFormat = '%.2f';
            app.regTh.ValueChangedFcn = createCallbackFcn(app, @regThValueChanged, true);
            app.RUNButton = uibutton(app.PartialVolumeCorrectionToolboxPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Image = uiimage(app.PartialVolumeCorrectionToolboxPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'MullerGartner', 'info.png');
            app.Label = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = muller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end