classdef rousset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PartialVolumeCorrectionToolboxPanel  matlab.ui.container.Panel
        SkullCheckBox                   matlab.ui.control.CheckBox
        MeningesCheckBox                matlab.ui.control.CheckBox
        InitialsofSubjectSpecificmaskEditField  matlab.ui.control.EditField
        InitialsofSubjectSpecificmaskEditFieldLabel  matlab.ui.control.Label
        OwnSubjectSpecificMaskCheckBox  matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        pathAtlas                       matlab.ui.control.Label
        AtlasButton                     matlab.ui.control.Button
        WMconsiderationDropDown         matlab.ui.control.DropDown
        SelectoneoptionLabel            matlab.ui.control.Label
        BinarizingThresholdSlider       matlab.ui.control.Slider
        BinarizingThresholdLabel        matlab.ui.control.Label
        InitialsofMRIimageEditField     matlab.ui.control.EditField
        InitialsofMRIimageEditFieldLabel  matlab.ui.control.Label
        InitialsofPETimageEditField     matlab.ui.control.EditField
        InitialsofPETimageEditFieldLabel  matlab.ui.control.Label
        zmmEditField                    matlab.ui.control.NumericEditField
        zmmEditFieldLabel               matlab.ui.control.Label
        ymmEditField                    matlab.ui.control.NumericEditField
        ymmEditFieldLabel               matlab.ui.control.Label
        xmmEditField                    matlab.ui.control.NumericEditField
        xmmEditFieldLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RUNButton                       matlab.ui.control.Button
        binTh                           matlab.ui.control.NumericEditField
        ScannerResolutionLabel          matlab.ui.control.Label
        pathLabel                       matlab.ui.control.Label
        SelectdirectoryButton           matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        RoussetLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        PathFolder
        atlasfullpath
        PSFx
        PSFy
        PSFz
        binTH
        whitematter
        pName
        mName
        subjectmaskName
        checkSubject
        meninges
        skull
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % mainApp = getappdata(0, 'mainApp');
            % 
            % % Cambia el panel visible en la aplicación principal
            % mainApp.PartialVolumeCorrectionMethodsPanel.Visible = 'on';
            % mainApp.PartialVolumeCorrectionToolboxPanel.Visible = 'off';
            % mainApp.QuantificationPanel.Visible = 'off';
            app1
            
            
            delete(app)
        end

        % Button pushed function: SelectdirectoryButton
        function SelectdirectoryButtonPushed(app, event)
            figure(app.UIFigure);
            app.PathFolder=uigetdir();
            figure(app.UIFigure);
            if app.PathFolder~=0
                app.pathLabel.Text = app.PathFolder;
            end

        end

        % Value changed function: InitialsofPETimageEditField
        function InitialsofPETimageEditFieldValueChanged(app, event)
            value = app.InitialsofPETimageEditField.Value;
            app.pName = value;
        end

        % Value changed function: InitialsofMRIimageEditField
        function InitialsofMRIimageEditFieldValueChanged(app, event)
            value = app.InitialsofMRIimageEditField.Value;
            app.mName = value;
        end

        % Value changed function: xmmEditField
        function xmmEditFieldValueChanged(app, event)
            value = app.xmmEditField.Value;
            app.PSFx = value;
        end

        % Value changed function: ymmEditField
        function ymmEditFieldValueChanged(app, event)
            value = app.ymmEditField.Value;
            app.PSFy = value;
        end

        % Value changed function: zmmEditField
        function zmmEditFieldValueChanged(app, event)
            value = app.zmmEditField.Value;
            app.PSFz = value;
        end

        % Value changed function: BinarizingThresholdSlider
        function BinarizingThresholdSliderValueChanged(app, event)
            value = app.BinarizingThresholdSlider.Value;
            app.binTh.Value = value;
            app.binTH = value;
        end

        % Value changed function: binTh
        function binThValueChanged(app, event)
            value = app.binTh.Value;
            app.BinarizingThresholdSlider.Value = value;
            app.binTH = value;
        end

        % Value changed function: WMconsiderationDropDown
        function WMconsiderationDropDownValueChanged(app, event)
            value = app.WMconsiderationDropDown.Value;
            app.whitematter = value;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)

            
            pathFolder=app.PathFolder;
            psfx=app.PSFx;
            psfy=app.PSFy;
            psfz=app.PSFz;
            BinTh=app.binTH;
            Method=app.whitematter;
            M1='Uniform White Matter';
            M2='Heterogeneous White Matter';
            PName=app.pName;
            MRIName=app.mName;
            Atlasfullpath = app.atlasfullpath;
            checkS = app.checkSubject;
            Meninges = app.meninges;
            Skull = app.skull;
            subjectMask = app.subjectmaskName;


            if ~isempty(Atlasfullpath)
                format=Atlasfullpath(end-3:end);
                Name=strfind(Atlasfullpath,'\');
                MName=Atlasfullpath(Name(end)+1:end);
            end
           

            if isempty(psfx) || isempty(psfy) || isempty(psfz)...
                    || isempty(pathFolder) || isempty(BinTh)...
                    || isempty(Method) || isequal(pathFolder,0) ...
                    || isempty(MRIName) || isempty(PName)
               clc
               msgbox('There is not enough input for initiating Partial Volume Correction', ...
                'Partial Volume Correction', 'warn');
               return;
            elseif isempty(Atlasfullpath) && ~any(checkS)
               clc
               msgbox('You must consider an atlas or a specific subject mask', ...
                'Partial Volume Correction', 'warn');
               return;
            elseif ~isempty(Atlasfullpath) && any(checkS)
               clc
               msgbox('You must consider an atlas or a specific subject mask, not both', ...
                'Partial Volume Correction', 'warn');
               return;
            elseif ~isempty(Atlasfullpath) && ~isempty(subjectMask)
                clc
               msgbox('You must consider an atlas or a specific subject mask, not both', ...
                'Partial Volume Correction', 'warn');
               return;
            elseif isempty(Atlasfullpath) && isempty(subjectMask)
                clc
               msgbox('You must consider an atlas or a specific subject mask', ...
                'Partial Volume Correction', 'warn');
               return;
            elseif BinTh>1 || BinTh<=0
                clc
               msgbox('Binarizing Threshold Should Be Between 0 and 1', ...
                'Partial Volume Correction', 'warn');
               return
            elseif psfx<=0 || psfy<=0 || psfz<=0
                clc
                msgbox('Scanner PSF is a positive value', ...
                'Partial Volume Correction', 'warn');
               return
           elseif Method == "Select one"
                clc
                msgbox('Select the WM consideration', ...
                'Partial Volume Correction', 'warn');
                return 
            elseif any(checkS) && isempty(subjectMask)
                clc
                msgbox('Specify the initials of the Subject Specific Mask', ...
                'Partial Volume Correction', 'warn');
                return 
            elseif ~isempty(subjectMask) && ~any(checkS)
                clc
                msgbox('Check the Subject Specific Mask box', ...
                'Partial Volume Correction', 'warn');
                return 
            end

            %% Reading files of subdirectory

            d = dir(pathFolder);
            isub = [d(:).isdir]; %# returns logical vector
            dirlist = {d(isub).name}';
            dirlist(1:2) = [];
            if isempty(dirlist) 
             box=msgbox({'There is no subdirectory in Pathfolder '},'Partial Volume Correction','warn');
               close(gcbf)
               return
            end 
            kk=1:length(dirlist);
            Variables=whos;
            save Variables
            
            %% SPM Defaults
            
            spm_get_defaults;
            spm_jobman('initcfg')
            clc
            %% PVC
            for kk=22:length(dirlist)
                
            wb=waitbar(0.1,['Loading files from ' dirlist{kk}],'Name', 'Rousset Partial Volume Correction') ; 
            
            pause(10)
            if ishandle(wb)
            MRIfile=dir([pathFolder '/' dirlist{kk} '/' MRIName,'*.nii']);
            MRIpath=[pathFolder '/' dirlist{kk} '/' MRIfile.name];
            
            
            PETfile=dir([pathFolder '/' dirlist{kk} '/' PName,'*.nii']);
            PETpath=[pathFolder '/' dirlist{kk} '/' PETfile.name];

            SUBJECTfile = dir([pathFolder '/' dirlist{kk} '/' subjectMask,'*.nii']);
            SUBJECTpath=[pathFolder '/' dirlist{kk} '/' SUBJECTfile.name];
            if any(Meninges)
                MENINGESfile = dir([pathFolder '/' dirlist{kk} '/Meninges' ,'*.nii']);
                if isempty(MENINGESfile)
                    clc
                    box=msgbox({'Add meninges file correctly for subject '; dirlist{kk}},'PARSLR Partial Volume Correction','warn');
                else 
                    MENINGESpath=[pathFolder '/' dirlist{kk} '/' MENINGESfile.name];
                end
                
            end
            if any(Skull)
                SKULLfile = dir([pathFolder '/' dirlist{kk} '/Skull' ,'*.nii']);
                if isempty(SKULLfile)
                    clc
                    box=msgbox({'Add skull file correctly for subject '; dirlist{kk}},'PARSLR Partial Volume Correction','warn');
                else 
                    SKULLpath=[pathFolder '/' dirlist{kk} '/' SKULLfile.name];
                end
            end

            %Copy source file
            mkdir([pathFolder '/' dirlist{kk}],'Sourcefiles')
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
            for pp=1:length(filepattern)
                copyfile([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name],[pathFolder '/' dirlist{kk} '/Sourcefiles'])
            end
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*_seg8.mat') )
            if ~isempty(filepattern)
            for tt=1:length(filepattern)    
            copyfile([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name],[pathFolder '/' dirlist{kk} '/Sourcefiles'])
            end
            wb=waitbar(0.2,wb,['Loading files from  ' dirlist{kk}]) ; 

            end
            pause(5)
            if  ishandle(wb)
                if ~any(checkS)
                mkdir([pathFolder '/' dirlist{kk}],'BrainAtlas')
                if strcmp(format,'.nii')==1
                copyfile(Atlasfullpath,[pathFolder '/' dirlist{kk} '/BrainAtlas']);
                AtlasFullPath = [pathFolder '/' dirlist{kk} '/BrainAtlas/' MName];
                elseif strcmp(format,'.hdr')==1
                copyfile(Atlasfullpath,[pathFolder '/' dirlist{kk} '/BrainAtlas']);
                copyfile([Atlasfullpath(1:end-4) '.img'],[pathFolder '/' dirlist{kk} '/BrainAtlas']);
                MName=[MName(1:end-4) '.img'];
                AtlasFullPath = [pathFolder '/' dirlist{kk} '/BrainAtlas/' MName(1:end-4) '.img'];
                elseif strcmp(format,'.img')==1
                copyfile(Atlasfullpath,[pathFolder '/' dirlist{kk} '/BrainAtlas']);
                copyfile([Atlasfullpath(1:end-4) '.hdr'],[pathFolder '/' dirlist{kk} '/BrainAtlas']);
                AtlasFullPath = [pathFolder '/' dirlist{kk} '/BrainAtlas/' MName];
                end
                end
                wb=waitbar(0.3,wb,['Processing files from ' dirlist{kk}],'Name', 'PARSLR Partial Volume Correction') ;
            else
                clc
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'PARSLR Partial Volume Correction','warn');
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                break
            end
            pause(5)
            if ishandle(wb)
            %Checking the segmented file
            GMcheck=dir([pathFolder '/' dirlist{kk} '/c1' MRIfile.name]);
            WMcheck=dir([pathFolder '/' dirlist{kk} '/c2' MRIfile.name]);
            CSFcheck=dir([pathFolder '/' dirlist{kk} '/c3' MRIfile.name]);
            InvFcheck=dir([pathFolder '/' dirlist{kk} '/iy_' MRIfile.name]);
            wb=waitbar(0.4,wb,['Segmentation for ' dirlist{kk}]) ;
            else
                clc
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'Rousset Partial Volume Correction','warn');
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                if ~any(checkS)
                rmdir([pathFolder '/' dirlist{kk} '/Brainatlas'],'s')
                end
                   
                break    
            end
            
            pause(30)
            if ishandle(wb)
            if isempty(GMcheck) || isempty(WMcheck)...   
               || isempty(CSFcheck) || isempty(InvFcheck)
            
                matlabbatch{1}.spm.spatial.preproc.channel.vols ={[MRIpath,',',int2str(1)]};
                matlabbatch{1}.spm.spatial.preproc.channel.biasreg = 0.0001;
                matlabbatch{1}.spm.spatial.preproc.channel.biasfwhm = 60;
                matlabbatch{1}.spm.spatial.preproc.channel.write = [0 0];
                matlabbatch{1}.spm.spatial.preproc.tissue(1).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(1)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(1).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(1).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(1).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(2).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(2)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(2).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(2).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(2).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(3).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(3)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(3).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(3).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(3).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(4).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(4)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(4).ngaus = 3;
                matlabbatch{1}.spm.spatial.preproc.tissue(4).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(4).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(5).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(5)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(5).ngaus = 4;
                matlabbatch{1}.spm.spatial.preproc.tissue(5).native = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(5).warped = [1 1];
                matlabbatch{1}.spm.spatial.preproc.tissue(6).tpm = {[fileparts(which('spm')) filesep 'tpm' filesep 'TPM.nii' ,',',int2str(6)]};
                matlabbatch{1}.spm.spatial.preproc.tissue(6).ngaus = 2;
                matlabbatch{1}.spm.spatial.preproc.tissue(6).native = [0 0];
                matlabbatch{1}.spm.spatial.preproc.tissue(6).warped = [0 0];
                matlabbatch{1}.spm.spatial.preproc.warp.mrf = 1;
                matlabbatch{1}.spm.spatial.preproc.warp.cleanup = 1;
                matlabbatch{1}.spm.spatial.preproc.warp.reg = [0 0.001 0.5 0.05 0.2];
                matlabbatch{1}.spm.spatial.preproc.warp.affreg = 'mni';
                matlabbatch{1}.spm.spatial.preproc.warp.fwhm = 0;
                matlabbatch{1}.spm.spatial.preproc.warp.samp = 3;
                matlabbatch{1}.spm.spatial.preproc.warp.write = [1 1];
                
                spm_jobman('run', matlabbatch);
                clear matlabbatch
                clc
            %Segmented files 
            
            GMpath=[pathFolder '/' dirlist{kk} '/c1' MRIfile.name];
            WMpath=[pathFolder '/' dirlist{kk} '/c2' MRIfile.name];
            CSFpath=[pathFolder '/' dirlist{kk} '/c3' MRIfile.name];
            InvFieldpath=[pathFolder '/' dirlist{kk} '/iy_' MRIfile.name];
            else
                GMpath=[pathFolder '/' dirlist{kk} '/' GMcheck.name];
                WMpath=[pathFolder '/' dirlist{kk}  '/' WMcheck.name];
                CSFpath=[pathFolder '/' dirlist{kk}  '/' CSFcheck.name];
                InvFieldpath=[pathFolder '/' dirlist{kk}  '/' InvFcheck.name];
                wb=waitbar(0.5,wb,['Preprocessing Images for  ' dirlist{kk}]) ;
            end
                else
                clc
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                if ~any(checkS)
                rmdir([pathFolder '/' dirlist{kk} '/BrainAtlas'],'s')
                end
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'Rousset Partial Volume Correction','warn');
                break
            end
            
            %% Normalizing Hammer Atlas to native space

            pause(10)
            if ishandle(wb)
            if ~any(checkS)
            Atlasvol = {[AtlasFullPath,',',int2str(1)]};
            
            matlabbatch{1}.spm.spatial.normalise.write.subj.def = {InvFieldpath};
            matlabbatch{1}.spm.spatial.normalise.write.subj.resample(1) = Atlasvol;
            matlabbatch{1}.spm.spatial.normalise.write.woptions.bb = [NaN NaN NaN; NaN NaN NaN];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.vox = [2 2 2];
            matlabbatch{1}.spm.spatial.normalise.write.woptions.interp = 0;
            spm_jobman('run', matlabbatch);
            clear matlabbatch
            clc
            end
                else
                clc    
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'Rousset Partial Volume Correction','warn');
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                if ~any(checkS)
                rmdir([pathFolder '/' dirlist{kk} '/BrainAtlas'],'s')
                end
                break
                
            end
            %% Reslice normalized Atlas to MRI dimensions
            wb=waitbar(0.6,wb,['Preprocessing Images from  ' dirlist{kk}]) ;
            %Coregister.Reslice
            pause(10)
            if ishandle(wb)
                if ~any(checkS)
            NAtlaspath=[pathFolder '/' dirlist{kk} '/BrainAtlas/w' MName];
            Image{1} = GMpath;
            Image{2} = NAtlaspath;
            
            
            flags = struct('mask',1,'interp',0,'mean',0,'which',1,'wrap',[0 0 0]);
            P = {Image{1:2}}';
            
            spm_reslice(P,flags) 
            clc
                end
                else
                clc
                box=msgbox({'Operation Cancelled for '; dirlist{kk}},'Rousset Partial Volume Correction','warn');
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                if ~any(checkS)
                rmdir([pathFolder '/' dirlist{kk} '/BrainAtlas'],'s')
                end
                break
            end
            %% Creating Patient Masks
            wb=waitbar(0.7,wb,['Preprocessing Images from  ' dirlist{kk}]) ;
            pause(10)
            if ishandle(wb)
                if ~any(checkS)
            RAtlaspath = [pathFolder '/' dirlist{kk} '/BrainAtlas/rw' MName];
            RAtlas_nii = load_untouch_nii(RAtlaspath);
            RAtlas = double(RAtlas_nii.img);
            RAtlas(isnan(RAtlas))=0;
            index=unique(RAtlas(RAtlas>0));
            for reindex=1:length(index)
                RAtlas(RAtlas==index(reindex))=reindex;
            end
            
            GMSegmnt = spm_read_vols(spm_vol(GMpath));
            GM=zeros(size(GMSegmnt));
            GM(GMSegmnt>BinTh)=1;
            GM_Mask=RAtlas.*GM;
            NewGM=GM_Mask;
            NewGM(GM_Mask~=0)=1;
            Unsgmnt_GM=(max(RAtlas(:))+1).*(GM-NewGM);
            NewGM_Mask=GM_Mask+Unsgmnt_GM;
            %Binarizing WM
            WMSegmnt = spm_read_vols(spm_vol(WMpath));
            WM=zeros(size(WMSegmnt));
            WM(WMSegmnt>0.5 & GM==0)=1;
            WM_Mask=RAtlas.*WM;
            t1=WM_Mask;
            t1(WM_Mask~=0)=1;
            %Binarizing CSf
            CSFSegmnt = spm_read_vols(spm_vol(CSFpath));
            CSF=zeros(size(CSFSegmnt));
            CSF(WM==0 & GM==0 & CSFSegmnt>0)=1;
            CSF_Mask=CSF.*RAtlas;
            NewCSF=CSF_Mask;
            NewCSF(CSF_Mask>0)=1;
            Unsgmnt_CSF=(2.*max(RAtlas(:))+2).*(CSF-NewCSF);
            New_CSF=zeros(size(CSF));
            New_CSF(CSF_Mask>0)=max(RAtlas(:))+1;
            New_CSFMask=CSF_Mask+New_CSF+Unsgmnt_CSF;
                end
                else
                clc    
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'PARSLR Partial Volume Correction','warn');
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                if ~any(checkS)
                rmdir([pathFolder '/' dirlist{kk} '/BrainAtlas'],'s')
                end
                break
            end
            %Brain Atlas
            wb=waitbar(0.8,wb,['Preprocessing Images from  ' dirlist{kk}]) ;
            pause(10)
            if ishandle(wb)
                if ~any(checkS)
            if strcmp(Method,M1)==1 
               New_WM_Mask=WM;
               New_WM_Mask(New_WM_Mask>0)=(2.*max(RAtlas(:))+3); 
            elseif strcmp(Method,M1)==0 && sum(t1(:))./sum(WM(WM==1))>=0.7
                New_WM=zeros(size(WM_Mask));
                New_WM(WM_Mask>0)=(2.*max(RAtlas(:))+2);
                New_WM_Mask=WM_Mask+New_WM;
                N=New_WM_Mask;
                N(New_WM_Mask~=0)=1;
                Missed_WM=(3.*max(RAtlas(:))+3).*(WM-N);
                New_WM_Mask=New_WM_Mask+Missed_WM;
            elseif strcmp(Method,M1)==0 && sum(t1(:))./sum(WM(WM==1))<0.7
                New_WM_Mask=WM;
                New_WM_Mask(New_WM_Mask>0)=(2.*max(RAtlas(:))+3);
               
            end
                end
            if ~any(checkS)
            Brain_Atlas=New_CSFMask+New_WM_Mask+NewGM_Mask;
            newname = [ 'Brain_Atlas_tr' num2str(BinTh) '_' MRIfile.name];

            HDR = spm_vol(PETpath);
            hdr = spm_vol(PETpath);
            hdr.fname=['Brain_Atlas_tr' num2str(BinTh) '_' MRIfile.nam];
            hdr.dim = size(Brain_Atlas);
            hdr.dt = [4,1];
            hdr.mat = HDR.mat;
            spm_write_vol(hdr,Brain_Atlas);

            CurrentDir=pwd;
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
            movefile(newname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1
             movefile(newname,dirlist{kk})   
            end
            clear hdr
            rmdir([pathFolder '/' dirlist{kk} '/BrainAtlas'],'s')
            end
            else
                clc    
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'PARSLR Partial Volume Correction','warn');
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                if ~any(checkS)
                rmdir([pathFolder '/' dirlist{kk} '/BrainAtlas'],'s')
                end
                break
            end
            
            
            %% Coregister and Reslice Brain Atlas 
            wb=waitbar(0.85,wb,['Preprocessing Images from  ' dirlist{kk}]) ;
            pause(10)
            if ishandle(wb)
                if ~any(checkS)
            
            PETvol={[PETpath,',',int2str(1)]};
            MRIvol={[MRIpath,',',int2str(1)]};
            %Brain Atlas
            
            BAtlaspath=[pathFolder '/' dirlist{kk} '/' newname];
            BAtlasvol={[BAtlaspath,',',int2str(1)]};
            
            
            matlabbatch{1}.spm.spatial.coreg.estimate.ref = PETvol;
            matlabbatch{1}.spm.spatial.coreg.estimate.source = MRIvol;
            matlabbatch{1}.spm.spatial.coreg.estimate.other = BAtlasvol;
            spm_jobman('run', matlabbatch);
            clear matlabbatch
            clc
                end
            else
                clc
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'PARSLR Partial Volume Correction','warn');  
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern)      
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
               delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end    
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                delete([pathFolder '/'  dirlist{kk} '/' newname])
                break
            end    
            %======================================================================================================    
            wb=waitbar(0.9,wb,['Preprocessing Images from  ' dirlist{kk}]) ;
            pause(10)
            if ishandle(wb)
                if ~any(checkS)
            % Reslice
            Image{1} = PETpath;
            Image{2} = MRIpath;
            Image{3} = BAtlaspath;
            
            flags = struct('mask',1,'interp',0,'mean',0,'which',1,'wrap',[0 0 0]);
            P = {Image{1:3}}';
            
            spm_reslice(P,flags) 
                elseif any(checkS)
            % Reslice
            Image{1} = PETpath;
            Image{2} = MRIpath;
            
            flags = struct('mask',1,'interp',0,'mean',0,'which',1,'wrap',[0 0 0]);
            P = {Image{1:2}}';
            
            spm_reslice(P,flags) 
                end
            else
                clc
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'PARSLR Partial Volume Correction','warn');
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern)      
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
               delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end  
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                delete([pathFolder '/'  dirlist{kk} '/' newname])
                break
            end    
            
            %% Rousset Partial Volume Correction
            wb=waitbar(0.95,wb,['Processing Images for PVC in  ' dirlist{kk}]) ;
            pause(5)
            if ishandle(wb)
            
            HDR = spm_vol(PETpath);
            PET = spm_read_vols(HDR);
            
            voxel_size_x = sqrt(sum(HDR.mat(1:3, 1).^2));
            voxel_size_y = sqrt(sum(HDR.mat(1:3, 2).^2));
            voxel_size_z = sqrt(sum(HDR.mat(1:3, 3).^2));
            PET_Voxel_size = [voxel_size_x, voxel_size_y, voxel_size_z];
            
            %Resliced Brain Atlas
            if ~any(checkS)
            rBAtlaspath=[pathFolder '/' dirlist{kk} '/r' newname];
            R_BAtlas_nii=load_untouch_nii(rBAtlaspath);
            R_BAtlas=double(R_BAtlas_nii.img);
            R_BAtlas(isnan(R_BAtlas))=0;

            delete(rBAtlaspath)
            Newname = ['r' newname];

            hdr = spm_vol(PETpath);
            hdr.fname=['r' newname];
            hdr.dim = size(R_BAtlas);
            hdr.dt = [4,1];
            hdr.mat = HDR.mat;
            spm_write_vol(hdr,R_BAtlas);
            
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
               movefile(Newname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1
             movefile(Newname,dirlist{kk})   
            end
            clear hdr
            end  
            
            %PSF
            A=zeros(9,9,9);
            A(5,5,5)=1;
            PSFx=smooth3(A,'gaussian',[9 1 1],psfx./(2.35.*PET_Voxel_size(1)));
            PSFxy=smooth3(PSFx,'gaussian',[1 9 1],psfy./(2.35.*PET_Voxel_size(2)));
            PSF=smooth3(PSFxy,'gaussian',[1 1 9],psfz./(2.35.*PET_Voxel_size(3)));
            FT_PSF=psf2otf(PSF,size(PET));
                else
                clc   
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern)    
                
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
                else
               delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end    
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'PARSLR Partial Volume Correction','warn');   
               break
            end
            %===============================================================================================================================
            wb=waitbar(0.97,wb,['Processing Images for PVC from  ' dirlist{kk}]) ;
            pause(10)
            if ishandle(wb)
                if any(checkS)
                    CurrentDir=pwd;
                    HDR = spm_vol(SUBJECTpath);
                    specificMask = spm_read_vols(HDR);
                    specificMask(isnan(specificMask))=0;
                                % Reslice WM
                    Image{1} = PETpath;
                    Image{2} = WMpath;
                    
                    flags = struct('mask',1,'interp',0,'mean',0,'which',1,'wrap',[0 0 0]);
                    P = {Image{1:2}}';
                    spm_reslice(P,flags) 

                    WMfile = dir([pathFolder '/' dirlist{kk} '/rc2' ,'*.nii'])
                    WMPATH=[pathFolder '/' dirlist{kk} '/' WMfile.name];
                    WMSegmnt = spm_read_vols(spm_vol(WMPATH));
                    WM = zeros(size(WMSegmnt));
                    WM(WMSegmnt>0.95) = 1;
                end
                if any(Meninges)
                    Meng = spm_read_vols(spm_vol(MENINGESpath));
                    Meng(isnan(Meng))=0;
                end
                if any(Skull)
                    skuL = spm_read_vols(spm_vol(SKULLpath));
                    skuL(isnan(skuL))=0;
                end

            if any(checkS) && any(Meninges) && ~any(Skull)
                
                Mng = zeros(size(Meng));
                Mng(Meng>0) = 173;
                wm = zeros(size(WM));
                wm(WM>0) = 174;

                Final_mask = specificMask;
                Final_mask(Mng>0)=173;
                Final_mask(wm>0) = 174;

            elseif any(checkS) && ~any(Meninges) && any(Skull)
                Skll = zeros(size(skuL));
                Skll(skuL>0) = 172;
                wm = zeros(size(WM));
                wm(WM>0) = 174;

                Final_mask = specificMask;
                Final_mask(Skll>0)=172;
                Final_mask(wm>0) = 174;

            elseif any(checkS) && any(Meninges) && any(Skull)
                Skll = zeros(size(skuL));
                Skll(skuL>0) = 172;
                Mng = zeros(size(Meng));
                Mng(Meng>0) = 173;
                wm = zeros(size(WM));
                wm(WM>0) = 174;
                
                Final_mask = specificMask;
                Final_mask(Skll>0)=172;
                Final_mask(Mng>0)=173;
                Final_mask(wm>0) = 174;

            elseif any(checkS) && ~any(Meninges) && ~any(Skull)
                wm = zeros(size(WM));
                wm(WM>0) = 174;
                Final_mask = specificMask;
                Final_mask(wm>0)=174;

            end
            if any(checkS)
                R_BAtlas_nii=load_untouch_nii(SUBJECTpath);

                Newname = ['FinalMask_',PETfile.name(1:end-4), '.nii'];

                hdr = spm_vol(PETpath);
                hdr.fname=['FinalMask_',PETfile.name(1:end-4), '.nii'];
                hdr.dim = size(Final_mask);
                hdr.dt = [4,1];
                hdr.mat = HDR.mat;
                spm_write_vol(hdr,Final_mask);

                R_BAtlas = Final_mask;
            end
            if ~any(checkS) && any(Meninges) && ~any(Skull) && ~isempty(Atlasfullpath)
                Mng = zeros(size(Meng));
                Mng(Meng>0) = 173;
                
                Final_mask = R_BAtlas;
                Final_mask(Mng>0) = 173;

                Newname = ['FinalMask_',PETfile.name(1:end-4), '.nii'];

                hdr = spm_vol(PETpath);
                hdr.fname=['FinalMask_',PETfile.name(1:end-4), '.nii'];
                hdr.dim = size(Final_mask);
                hdr.dt = [4,1];
                hdr.mat = HDR.mat;
                spm_write_vol(hdr,Final_mask);
                R_BAtlas = Final_mask;

            elseif ~any(checkS) && ~any(Meninges) && any(Skull) && ~isempty(Atlasfullpath)
                Skll = zeros(size(skuL));
                Skll(skuL>0) = 172;
                
                Final_mask = R_BAtlas;
                Final_mask(Skll>0)=172;

                Newname = ['FinalMask_',PETfile.name(1:end-4), '.nii'];

                hdr = spm_vol(PETpath);
                hdr.fname=['FinalMask_',PETfile.name(1:end-4), '.nii'];
                hdr.dim = size(Final_mask);
                hdr.dt = [4,1];
                hdr.mat = HDR.mat;
                spm_write_vol(hdr,Final_mask);
                R_BAtlas = Final_mask;
            elseif ~any(checkS) && any(Meninges) && any(Skull) && ~isempty(Atlasfullpath)
                Mng = zeros(size(Meng));
                Mng(Meng>0) = 173;
                
                Skll = zeros(size(skuL));
                Skll(skuL>0) = 172;

                Final_mask = R_BAtlas;
                Final_mask(Mng>0)=173;
                Final_mask(Skll>0)=172;

                Newname = ['FinalMask_',PETfile.name(1:end-4), '.nii'];

                hdr = spm_vol(PETpath);
                hdr.fname=['FinalMask_',PETfile.name(1:end-4), '.nii'];
                hdr.dim = size(Final_mask);
                hdr.dt = [4,1];
                hdr.mat = HDR.mat;
                spm_write_vol(hdr,Final_mask);

                R_BAtlas = Final_mask;
            end
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
               movefile(Newname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1
             movefile(Newname,dirlist{kk})   
            end
            clear hdr

            %GTM
            Index=unique(R_BAtlas(R_BAtlas~=0));
            for m=1:length(Index)
                m
                mask1=double(ismember(R_BAtlas,Index(m)));%mask for each region 
                Smooth_Mask1=ifftn(fftn(mask1).*FT_PSF);%Smoothing mask
                Region=PET.*mask1;%Extracting regions from observed image       
                Observed(1,m)=sum(Region(:))./sum(mask1(:));%Observed activity in each region
                for n=1:length(Index)
                    mask2=double(ismember(R_BAtlas,Index(n)));      
                    Spill=Smooth_Mask1.*mask2;
                    Spill = double(Spill);
                    GTM(m,n)=sum(Spill(:))./sum(mask2(:));%GTM matrix
                end
            end
            
            Recovered_Regions=Observed/GTM;   %Recovered activity
            
            else
                clc
                   filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
            for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
            end
                   
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
            for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
            end
                
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
               delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end    
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'PARSLR Partial Volume Correction','warn');
                   
            break
            end
            wb=waitbar(0.98,wb,['Processing Images for PVC in  ' dirlist{kk}]) ;
            pause(5)
            if ishandle(wb)
            RecovrdIm=zeros(size(PET));
            for mm=1:length(Index)   
                RecovrdIm(R_BAtlas==Index(mm))=Recovered_Regions(mm);
                RecovrdIm(RecovrdIm<0)=0;
            end
            if ~any(checkS)
            Re_GM=zeros(size(R_BAtlas));
            Re_GM(R_BAtlas>0 & R_BAtlas<max(NewGM_Mask(:)))=1;
            Rec_GM=RecovrdIm.*Re_GM;
            elseif any(checkS)
                 GM_new = zeros(size(specificMask));
                 GM_new(specificMask>0) = 1;
                 Rec_GM=RecovrdIm.*GM_new;
            end
            
            Newname = [PETfile.name(1:end-4),'_RPVC_tr', num2str(BinTh), '.nii'];

            hdr = spm_vol(PETpath);
            hdr.fname=[PETfile.name(1:end-4),'_RPVC_tr', num2str(BinTh), '.nii'];
            hdr.dim = size(RecovrdIm);
            hdr.dt = [4,1];
            hdr.mat = HDR.mat;
            spm_write_vol(hdr,RecovrdIm);
            
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
               movefile(Newname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1
             movefile(Newname,dirlist{kk})   
            end
            
            clear hdr
            clear RecovrdIm

            GNewname = ['GM_' PETfile.name(1:end-4),'_RPVC_tr', num2str(BinTh), '.nii'];

            hdr = spm_vol(PETpath);
            hdr.fname=['GM_' PETfile.name(1:end-4),'_RPVC_tr', num2str(BinTh), '.nii'];
            hdr.dim = size(Rec_GM);
            hdr.dt = [4,1];
            hdr.mat = HDR.mat;
            spm_write_vol(hdr,Rec_GM);
            
            if strcmp(CurrentDir,pathFolder)~=1 && strcmp(CurrentDir,[pathFolder '/' dirlist{kk}])~=1
               movefile(GNewname,[pathFolder '/' dirlist{kk}])
            elseif strcmp(CurrentDir,pathFolder)==1
             movefile(GNewname,dirlist{kk})   
            end
            clear hdr
            clear Rec_GM
            else
                clc
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
                for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
                end
                  
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
            for tt=1:length(filepattern)    
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
            end
            else
               delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end    
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'PARSLR Partial Volume Correction','warn');  
               break
            end
            
            wb=waitbar(1,wb,['Done Partial Volume Correction for  ' dirlist{kk}]) ;
            pause(10)
            if  kk~=length(dirlist) && ishandle(wb)
            delete(wb)
            clear all
            load Variables.mat;
            clc
            spm_get_defaults;
            spm_jobman('initcfg')
            clc
            elseif kk==length(dirlist) && ishandle(wb)
            savefig(wb,'wb')
            else
               clc
               filepattern=dir(fullfile([pathFolder '/' dirlist{kk}],'*.nii') );
            for pp=1:length(filepattern)
                    delete([pathFolder '/' dirlist{kk} '/'  filepattern(pp).name])
            end
            
            filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*.nii') );
                for pp=1:length(filepattern)
                    copyfile([pathFolder '/' dirlist{kk} '/Sourcefiles/'  filepattern(pp).name],[pathFolder '/' dirlist{kk}])
                end
                
                filepattern=dir(fullfile([pathFolder '/' dirlist{kk} '/Sourcefiles'],'*_seg8.mat') );
            if ~isempty(filepattern)
             for tt=1:length(filepattern)   
                delete([pathFolder '/'  dirlist{kk} '/' filepattern(tt).name])
                copyfile([pathFolder '/'  dirlist{kk} '/Sourcefiles/' filepattern(tt).name],[pathFolder '/' dirlist{kk}])
             end
                else
               delete([pathFolder '/'  dirlist{kk} '/' MRIfile.name(1:end-4) '_seg8.mat'])
            end    
                rmdir([pathFolder '/' dirlist{kk} '/Sourcefiles'],'s')
                box=msgbox({'PVC Cancelled for '; dirlist{kk}},'Rousset Partial Volume Correction','warn');
               break
            end
            end
            end
            %% Output
            O1={['Rousset PVC is done for ' pathFolder]};
            O2={['Scanner point spread function is ' num2str(psfx) ' '  num2str(psfy) ' ' num2str(psfz)]};
            O3={['Binarizing Threshold is ' num2str(BinTh)]};
            O4={['White Matter considered as ' Method]};
            O5={['Scaling Mask Directory is ' Atlasfullpath]};
            O6={['Initials of PET is ' PName]};
            O7={['Initials of MRI is ' MRIName]};
            if any(Meninges)
            O8={['Meninges mask is considered ']};
            end
            if any(Skull)
            O9={['Skull mask is considered ']};
            end
            if any(checkS)
            O10={['Subject specific mask is considered with initials ' subjectMask]}; 
            end

            fid=fopen([pathFolder '/Rousset_parameters.txt'],'w');
            fprintf(fid,'%s\n',O1{:});
            fprintf(fid,'%s\n',O2{:});
            fprintf(fid,'%s\n',O3{:});
            fprintf(fid,'%s\n',O4{:});
            fprintf(fid,'%s\n',O5{:});
            fprintf(fid,'%s\n',O6{:});
            fprintf(fid,'%s\n',O7{:});
            if any(Meninges)
            fprintf(fid,'%s\n',O8{:});
            end
            if any(Skull)
            fprintf(fid,'%s\n',O9{:});
            end
            if any(checkS)
            fprintf(fid,'%s\n',O10{:});
            end

            %% 

            app.pathLabel.Text = '';
            app.xmmEditField.Value= 0;
            app.ymmEditField.Value= 0;
            app.zmmEditField.Value= 0;
            app.binTh.Value = 0;
            app.BinarizingThresholdSlider.Value = 0;
            app.WMconsiderationDropDown.Value = "Select one";
            app.InitialsofMRIimageEditField.Value = '';
            app.InitialsofPETimageEditField.Value = '';
            if ~isempty(app.atlasfullpath)
                app.pathAtlas.Text = '';
            end
            app.OwnSubjectSpecificMaskCheckBox.Value = 0;

            app.MeningesCheckBox.Value = 0;
            app.SkullCheckBox.Value = 0;
            app.InitialsofSubjectSpecificmaskEditField.Value = '';


        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            roussetINFO
        end

        % Button pushed function: AtlasButton
        function AtlasButtonPushed(app, event)
           
            app.atlasfullpath=spm_select(1);
            if ~isempty(app.atlasfullpath)
                app.pathAtlas.Text = app.atlasfullpath;
            end
            figure(app.UIFigure);
        end

        % Value changed function: OwnSubjectSpecificMaskCheckBox
        function OwnSubjectSpecificMaskCheckBoxValueChanged(app, event)
            value = app.OwnSubjectSpecificMaskCheckBox.Value;
            app.checkSubject = value;
            
        end

        % Value changed function: MeningesCheckBox
        function MeningesCheckBoxValueChanged(app, event)
            value = app.MeningesCheckBox.Value;
            app.meninges = value;
        end

        % Value changed function: SkullCheckBox
        function SkullCheckBoxValueChanged(app, event)
            value = app.SkullCheckBox.Value;
            app.skull = value;
        end

        % Value changed function: InitialsofSubjectSpecificmaskEditField
        function InitialsofSubjectSpecificmaskEditFieldValueChanged(app, event)
            value = app.InitialsofSubjectSpecificmaskEditField.Value;
            app.subjectmaskName = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PartialVolumeCorrectionToolboxPanel = uipanel(app.UIFigure);
            app.PartialVolumeCorrectionToolboxPanel.Title = 'Partial Volume Correction Toolbox';
            app.RoussetLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.RoussetLabel.Text = 'Rousset';
            app.BackButton = uibutton(app.PartialVolumeCorrectionToolboxPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.SelectdirectoryButton = uibutton(app.PartialVolumeCorrectionToolboxPanel, 'push');
            app.SelectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirectoryButtonPushed, true);
            app.SelectdirectoryButton.Text = 'Select directory';
            app.pathLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.pathLabel.Enable = 'off';
            app.pathLabel.Text = '*path';
            app.ScannerResolutionLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.ScannerResolutionLabel.Text = 'Scanner Resolution:';
            app.binTh = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.binTh.Limits = [0 1];
            app.binTh.ValueDisplayFormat = '%.2f';
            app.binTh.ValueChangedFcn = createCallbackFcn(app, @binThValueChanged, true);
            app.RUNButton = uibutton(app.PartialVolumeCorrectionToolboxPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Image = uiimage(app.PartialVolumeCorrectionToolboxPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'MullerGartner', 'info.png');
            app.xmmEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.xmmEditFieldLabel.Text = 'x (mm)';
            app.xmmEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.xmmEditField.ValueChangedFcn = createCallbackFcn(app, @xmmEditFieldValueChanged, true);
            app.ymmEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.ymmEditFieldLabel.Text = 'y (mm)';
            app.ymmEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.ymmEditField.ValueChangedFcn = createCallbackFcn(app, @ymmEditFieldValueChanged, true);
            app.zmmEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.zmmEditFieldLabel.Text = 'z (mm)';
            app.zmmEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'numeric');
            app.zmmEditField.ValueChangedFcn = createCallbackFcn(app, @zmmEditFieldValueChanged, true);
            app.InitialsofPETimageEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.InitialsofPETimageEditFieldLabel.Text = 'Initials of PET image:';
            app.InitialsofPETimageEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'text');
            app.InitialsofPETimageEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsofPETimageEditFieldValueChanged, true);
            app.InitialsofMRIimageEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.InitialsofMRIimageEditFieldLabel.Text = 'Initials of MRI image:';
            app.InitialsofMRIimageEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'text');
            app.InitialsofMRIimageEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsofMRIimageEditFieldValueChanged, true);
            app.BinarizingThresholdLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.BinarizingThresholdLabel.Text = 'Binarizing Threshold:';
            app.BinarizingThresholdSlider = uislider(app.PartialVolumeCorrectionToolboxPanel);
            app.BinarizingThresholdSlider.Limits = [0 1];
            app.BinarizingThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinarizingThresholdSliderValueChanged, true);
            app.SelectoneoptionLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.SelectoneoptionLabel.Text = 'WM consideration:';
            app.WMconsiderationDropDown = uidropdown(app.PartialVolumeCorrectionToolboxPanel);
            app.WMconsiderationDropDown.Items = {'Select one', 'Uniform White Matter', 'Heterogeneous White Matter'};
            app.WMconsiderationDropDown.ValueChangedFcn = createCallbackFcn(app, @WMconsiderationDropDownValueChanged, true);
            app.WMconsiderationDropDown.Value = 'Select one';
            app.AtlasButton = uibutton(app.PartialVolumeCorrectionToolboxPanel, 'push');
            app.AtlasButton.ButtonPushedFcn = createCallbackFcn(app, @AtlasButtonPushed, true);
            app.AtlasButton.Text = 'Atlas';
            app.pathAtlas = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.pathAtlas.Enable = 'off';
            app.pathAtlas.Text = '*path';
            app.Label = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.Label_2.Text = '';
            app.OwnSubjectSpecificMaskCheckBox = uicheckbox(app.PartialVolumeCorrectionToolboxPanel);
            app.OwnSubjectSpecificMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @OwnSubjectSpecificMaskCheckBoxValueChanged, true);
            app.OwnSubjectSpecificMaskCheckBox.Text = 'Own Subject Specific Mask';
            app.InitialsofSubjectSpecificmaskEditFieldLabel = uilabel(app.PartialVolumeCorrectionToolboxPanel);
            app.InitialsofSubjectSpecificmaskEditFieldLabel.Text = 'Initials of Subject Specific mask:';
            app.InitialsofSubjectSpecificmaskEditField = uieditfield(app.PartialVolumeCorrectionToolboxPanel, 'text');
            app.InitialsofSubjectSpecificmaskEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsofSubjectSpecificmaskEditFieldValueChanged, true);
            app.MeningesCheckBox = uicheckbox(app.PartialVolumeCorrectionToolboxPanel);
            app.MeningesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeningesCheckBoxValueChanged, true);
            app.MeningesCheckBox.Text = 'Meninges';
            app.SkullCheckBox = uicheckbox(app.PartialVolumeCorrectionToolboxPanel);
            app.SkullCheckBox.ValueChangedFcn = createCallbackFcn(app, @SkullCheckBoxValueChanged, true);
            app.SkullCheckBox.Text = 'Skull';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rousset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end